classdef DPParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DPParametersUIFigure           matlab.ui.Figure
        CancelButton                   matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        ParametersPanel                matlab.ui.container.Panel
        NormalizeddepthlimitscalculationsPanel  matlab.ui.container.Panel
        NormalizedDepthHighLimitEditField  matlab.ui.control.NumericEditField
        HighlimitEditFieldLabel        matlab.ui.control.Label
        NormalizedDepthLowLimitEditField  matlab.ui.control.NumericEditField
        LowlimitEditFieldLabel         matlab.ui.control.Label
        DICparametersPanel             matlab.ui.container.Panel
        PillarReductionFactorDropDown  matlab.ui.control.DropDown
        PillarreductionfactorDropDownLabel  matlab.ui.control.Label
        DataSmoothingParametersPanel   matlab.ui.container.Panel
        PolynomialDegreeSmoothingEditField  matlab.ui.control.Spinner
        PolynomialdegreeLabel          matlab.ui.control.Label
        PointsSpanSmoothingEditField   matlab.ui.control.Spinner
        PointsspanLabel                matlab.ui.control.Label
        MaterialPropertiesPanel        matlab.ui.container.Panel
        ElasticModulusLabel            matlab.ui.control.Label
        PoissonRatioEditField          matlab.ui.control.NumericEditField
        ElasticModulusEditField        matlab.ui.control.NumericEditField
        PoissonRatioLabel              matlab.ui.control.Label
        MillingParametersPanel         matlab.ui.container.Panel
        PillarDiameterLabel            matlab.ui.control.Label
        StepSizeEditField              matlab.ui.control.NumericEditField
        PillarDiameterEditField        matlab.ui.control.NumericEditField
        StepSizeLabel                  matlab.ui.control.Label
        DataSelectionPanel             matlab.ui.container.Panel
        Browse45StrainButton           matlab.ui.control.Button
        FortyFiveStrainEditField       matlab.ui.control.EditField
        FortyFiveStrainLabel           matlab.ui.control.Label
        Browse90StrainButton           matlab.ui.control.Button
        NinetyStrainEditField          matlab.ui.control.EditField
        NinetyStrainLabel              matlab.ui.control.Label
        Browse0StrainButton            matlab.ui.control.Button
        ZeroStrainEditField            matlab.ui.control.EditField
        ZeroStrainLabel                matlab.ui.control.Label
        StressStateAssumptionButtonGroup  matlab.ui.container.ButtonGroup
        FullFieldButton                matlab.ui.control.RadioButton
        NonEquibiaxialButton           matlab.ui.control.RadioButton
        EquibiaxialButton              matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        %% Data selection tab
        stressStateAssumption string % 'equibiaxial', 'nonEquibiaxial' or 'fullField'

        zeroStrainFile
        zeroStrainPath
        ninetyStrainFile
        ninetyStrainPath
        fortyFiveStrainFile
        fortyFiveStrainPath

        %% Parameters tab
        % Geometrical properties
        pillarDiameter
        stepSize
        
        % DIC parameters
        pillarRedFactor
        
        % Smoothing parameters
        pointsSpan
        polyDegree

        % Material properties
        elasticModulus
        poissonRatio

        normalizedDepthLimits

        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, stressStateAssumption, zeroStrainFile, zeroStrainPath, ninetyStrainFile, ninetyStrainPath, fortyFiveStrainFile, fortyFiveStrainPath, pillarDiameter, stepSize, pillarRedFactor, pointsSpan, polyDegree, elasticModulus, poissonRatio, normalizedDepthLimits)
            %% Data selection tab
            app.ZeroStrainEditField.Value = zeroStrainFile;
            app.zeroStrainPath = zeroStrainPath;
            app.NinetyStrainEditField.Value = ninetyStrainFile;
            app.ninetyStrainPath = ninetyStrainPath;
            app.FortyFiveStrainEditField.Value = fortyFiveStrainFile;
            app.fortyFiveStrainPath = fortyFiveStrainPath;
            
            % Assumption on stress state
            switch stressStateAssumption
                case 'equibiaxial'
                    app.EquibiaxialButton.Value = true;
                case 'nonEquibiaxial'
                    app.NonEquibiaxialButton.Value = true;
                case 'fullField'
                    app.FullFieldButton.Value = true;
            end
            
            %% Parameters tab
            % Geometrical properties
            app.PillarDiameterEditField.Value = pillarDiameter;
            app.StepSizeEditField.Value = stepSize;

            % Material properties
            app.ElasticModulusEditField.Value = elasticModulus;
            app.PoissonRatioEditField.Value = poissonRatio;

            % Normalized depth limits
            app.NormalizedDepthLowLimitEditField.Value = normalizedDepthLimits(1);
            app.NormalizedDepthHighLimitEditField.Value = normalizedDepthLimits(2);

            % Smoothing parameters
            app.PointsSpanSmoothingEditField.Value = pointsSpan;
            app.PolynomialDegreeSmoothingEditField.Value = polyDegree;

            % DIC parameters
            app.PillarReductionFactorDropDown.Value = num2str(pillarRedFactor);

            app.mainapp = mainapp;

            StressStateAssumptionChangeCallback(app)
        end

        % Button pushed function: OKButton
        function OKCallback(app, event)
            %% Data selection tab
            % Assumption on stress state
            if app.EquibiaxialButton.Value
                app.mainapp.stressStateAssumption = "equibiaxial";
            elseif app.NonEquibiaxialButton.Value
                app.mainapp.stressStateAssumption = "nonEquibiaxial";
            elseif app.FullFieldButton.Value
                app.mainapp.stressStateAssumption = "fullField";
            end

            % Paths
            app.mainapp.zeroStrainFile = app.ZeroStrainEditField.Value;
            app.mainapp.zeroStrainPath = app.zeroStrainPath;
            app.mainapp.ninetyStrainFile = app.NinetyStrainEditField.Value;
            app.mainapp.ninetyStrainPath = app.ninetyStrainPath;
            app.mainapp.fortyFiveStrainFile = app.FortyFiveStrainEditField.Value;
            app.mainapp.fortyFiveStrainPath = app.fortyFiveStrainPath;

            % Milling parameters
            app.mainapp.pillarDiameter = app.PillarDiameterEditField.Value;
            app.mainapp.stepSize = app.StepSizeEditField.Value;

            % Material properties
            app.mainapp.elasticModulus = app.ElasticModulusEditField.Value;
            app.mainapp.poissonRatio = app.PoissonRatioEditField.Value;

            % Normalized depth limits
            app.mainapp.normalizedDepthLimits = [app.NormalizedDepthLowLimitEditField.Value app.NormalizedDepthHighLimitEditField.Value];

            % Smoothing parameters
            app.mainapp.pointsSpan = app.PointsSpanSmoothingEditField.Value;
            app.mainapp.polyDegree = app.PolynomialDegreeSmoothingEditField.Value;

            % DIC parameters
            app.mainapp.pillarRedFactor = str2double(app.PillarReductionFactorDropDown.Value);

            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelCallback(app, event)
            delete(app)
        end

        % Selection changed function: StressStateAssumptionButtonGroup
        function StressStateAssumptionChangeCallback(app, event)
            if app.EquibiaxialButton.Value
                app.NinetyStrainLabel.Enable = "off";
                app.NinetyStrainEditField.Enable = "off";
                app.Browse90StrainButton.Enable = "off";

                app.FortyFiveStrainLabel.Enable = "off";
                app.FortyFiveStrainEditField.Enable = "off";
                app.Browse45StrainButton.Enable = "off";

            elseif app.NonEquibiaxialButton.Value
                app.NinetyStrainLabel.Enable = "on";
                app.NinetyStrainEditField.Enable = "on";
                app.Browse90StrainButton.Enable = "on";

                app.FortyFiveStrainLabel.Enable = "off";
                app.FortyFiveStrainEditField.Enable = "off";
                app.Browse45StrainButton.Enable = "off";
                
            else
                app.NinetyStrainLabel.Enable = "on";
                app.NinetyStrainEditField.Enable = "on";
                app.Browse90StrainButton.Enable = "on";

                app.FortyFiveStrainLabel.Enable = "on";
                app.FortyFiveStrainEditField.Enable = "on";
                app.Browse45StrainButton.Enable = "on";
            end
        end

        % Button pushed function: Browse0StrainButton
        function BrowseZeroStrainCallback(app, event)
            [app.zeroStrainFile, app.zeroStrainPath] = uigetfile('*.dat','Select the strain file for x.');
            if app.zeroStrainFile == 0 | isempty(app.zeroStrainFile)
                return
            end
            app.ZeroStrainEditField.Value = app.zeroStrainFile;
        end

        % Button pushed function: Browse90StrainButton
        function BrowseNinetyStrainCallback(app, event)
            [app.ninetyStrainFile, app.ninetyStrainPath] = uigetfile('*.dat','Select the strain file for y.');
            if app.ninetyStrainFile == 0 | isempty(app.ninetyStrainFile)
                return
            end
            app.NinetyStrainEditField.Value = app.ninetyStrainFile;
        end

        % Button pushed function: Browse45StrainButton
        function BrowseFortyFiveStrainCallback(app, event)
            [app.fortyFiveStrainFile, app.fortyFiveStrainPath] = uigetfile('*.dat','Select the strain file for 45°.');
            if app.fortyFiveStrainFile == 0 | isempty(app.fortyFiveStrainFile)
                return
            end
            app.FortyFiveStrainEditField.Value = app.fortyFiveStrainFile;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DPParametersUIFigure = uifigure('Visible', 'off');
            app.DPParametersUIFigure.Name = 'Depth profiling parameters';
            app.DPParametersUIFigure.Icon = fullfile(pathToMLAPP, '..', 'rsdp_resources', 'icon-rsdp.png');
            app.DataSelectionPanel = uipanel(app.DPParametersUIFigure);
            app.DataSelectionPanel.Title = 'Data selection';
            app.StressStateAssumptionButtonGroup = uibuttongroup(app.DataSelectionPanel);
            app.StressStateAssumptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StressStateAssumptionChangeCallback, true);
            app.StressStateAssumptionButtonGroup.Tooltip = {''};
            app.StressStateAssumptionButtonGroup.Title = 'Assumption on stress state';
            app.EquibiaxialButton = uiradiobutton(app.StressStateAssumptionButtonGroup);
            app.EquibiaxialButton.Tooltip = {'Requires strain in 1 direction'};
            app.EquibiaxialButton.Text = 'Equibiaxial';
            app.EquibiaxialButton.Value = true;
            app.NonEquibiaxialButton = uiradiobutton(app.StressStateAssumptionButtonGroup);
            app.NonEquibiaxialButton.Tooltip = {'Requires strains in 2 directions'};
            app.NonEquibiaxialButton.Text = 'Biaxial (known principal directions: 0°, 90°)';
            app.FullFieldButton = uiradiobutton(app.StressStateAssumptionButtonGroup);
            app.FullFieldButton.Tooltip = {'Requires strains in 3 directions'};
            app.FullFieldButton.Text = 'Biaxial (unknown principal directions)';
            app.ZeroStrainLabel = uilabel(app.DataSelectionPanel);
            app.ZeroStrainLabel.Text = 'Strain in 0° direction';
            app.ZeroStrainEditField = uieditfield(app.DataSelectionPanel, 'text');
            app.ZeroStrainEditField.Editable = 'off';
            app.Browse0StrainButton = uibutton(app.DataSelectionPanel, 'push');
            app.Browse0StrainButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseZeroStrainCallback, true);
            app.Browse0StrainButton.Text = 'Browse...';
            app.NinetyStrainLabel = uilabel(app.DataSelectionPanel);
            app.NinetyStrainLabel.Text = 'Strain in 90° direction';
            app.NinetyStrainEditField = uieditfield(app.DataSelectionPanel, 'text');
            app.NinetyStrainEditField.Editable = 'off';
            app.Browse90StrainButton = uibutton(app.DataSelectionPanel, 'push');
            app.Browse90StrainButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseNinetyStrainCallback, true);
            app.Browse90StrainButton.Text = 'Browse...';
            app.FortyFiveStrainLabel = uilabel(app.DataSelectionPanel);
            app.FortyFiveStrainLabel.Text = 'Strain in 45° direction';
            app.FortyFiveStrainEditField = uieditfield(app.DataSelectionPanel, 'text');
            app.FortyFiveStrainEditField.Editable = 'off';
            app.Browse45StrainButton = uibutton(app.DataSelectionPanel, 'push');
            app.Browse45StrainButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFortyFiveStrainCallback, true);
            app.Browse45StrainButton.Text = 'Browse...';
            app.ParametersPanel = uipanel(app.DPParametersUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.MillingParametersPanel = uipanel(app.ParametersPanel);
            app.MillingParametersPanel.Title = 'Milling parameters';
            app.StepSizeLabel = uilabel(app.MillingParametersPanel);
            app.StepSizeLabel.Text = 'Step size [µm]';
            app.PillarDiameterEditField = uieditfield(app.MillingParametersPanel, 'numeric');
            app.PillarDiameterEditField.Limits = [0 Inf];
            app.PillarDiameterEditField.AllowEmpty = 'on';
            app.PillarDiameterEditField.Value = 10;
            app.StepSizeEditField = uieditfield(app.MillingParametersPanel, 'numeric');
            app.StepSizeEditField.Limits = [0 Inf];
            app.StepSizeEditField.AllowEmpty = 'on';
            app.StepSizeEditField.Value = 0.25;
            app.PillarDiameterLabel = uilabel(app.MillingParametersPanel);
            app.PillarDiameterLabel.Text = 'Pillar diameter [µm]';
            app.MaterialPropertiesPanel = uipanel(app.ParametersPanel);
            app.MaterialPropertiesPanel.Title = 'Material properties';
            app.PoissonRatioLabel = uilabel(app.MaterialPropertiesPanel);
            app.PoissonRatioLabel.Text = 'Poisson ratio';
            app.ElasticModulusEditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.ElasticModulusEditField.AllowEmpty = 'on';
            app.ElasticModulusEditField.Value = 120;
            app.PoissonRatioEditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.PoissonRatioEditField.Limits = [0 Inf];
            app.PoissonRatioEditField.AllowEmpty = 'on';
            app.PoissonRatioEditField.Value = 0.36;
            app.ElasticModulusLabel = uilabel(app.MaterialPropertiesPanel);
            app.ElasticModulusLabel.Text = 'Elastic modulus [GPa]';
            app.DataSmoothingParametersPanel = uipanel(app.ParametersPanel);
            app.DataSmoothingParametersPanel.Title = 'Data smoothing parameters';
            app.PointsspanLabel = uilabel(app.DataSmoothingParametersPanel);
            app.PointsspanLabel.Tooltip = {'Before the residual stress is calculated, the strain is smoothed using a Savitzky-Golay algorithm. This fits a polynomial over a number of points.'};
            app.PointsspanLabel.Text = 'Points span';
            app.PointsSpanSmoothingEditField = uispinner(app.DataSmoothingParametersPanel);
            app.PointsSpanSmoothingEditField.AllowEmpty = 'on';
            app.PointsSpanSmoothingEditField.Tooltip = {'Before the residual stress is calculated, the strain is smoothed using a Savitzky-Golay algorithm. This fits a polynomial over a number of points.'};
            app.PointsSpanSmoothingEditField.Value = 6;
            app.PolynomialdegreeLabel = uilabel(app.DataSmoothingParametersPanel);
            app.PolynomialdegreeLabel.Tooltip = {'Before the residual stress is calculated, the strain is smoothed using a Savitzky-Golay algorithm. This fits a polynomial over a number of points.'};
            app.PolynomialdegreeLabel.Text = 'Polynomial degree';
            app.PolynomialDegreeSmoothingEditField = uispinner(app.DataSmoothingParametersPanel);
            app.PolynomialDegreeSmoothingEditField.AllowEmpty = 'on';
            app.PolynomialDegreeSmoothingEditField.Tooltip = {'Before the residual stress is calculated, the strain is smoothed using a Savitzky-Golay algorithm. This fits a polynomial over a number of points.'};
            app.PolynomialDegreeSmoothingEditField.Value = 3;
            app.DICparametersPanel = uipanel(app.ParametersPanel);
            app.DICparametersPanel.Title = 'DIC parameters';
            app.PillarreductionfactorDropDownLabel = uilabel(app.DICparametersPanel);
            app.PillarreductionfactorDropDownLabel.Text = 'Pillar reduction factor';
            app.PillarReductionFactorDropDown = uidropdown(app.DICparametersPanel);
            app.PillarReductionFactorDropDown.Items = {'1', '0.9', '0.8', '0.7', '0.6'};
            app.PillarReductionFactorDropDown.Value = '0.8';
            app.NormalizeddepthlimitscalculationsPanel = uipanel(app.ParametersPanel);
            app.NormalizeddepthlimitscalculationsPanel.Tooltip = {'Those values represent the interval where values of normalized depth will be interpolated before the calculation of stress.'};
            app.NormalizeddepthlimitscalculationsPanel.Title = 'Normalized depth limits (calculations)';
            app.LowlimitEditFieldLabel = uilabel(app.NormalizeddepthlimitscalculationsPanel);
            app.LowlimitEditFieldLabel.Text = 'Low limit';
            app.NormalizedDepthLowLimitEditField = uieditfield(app.NormalizeddepthlimitscalculationsPanel, 'numeric');
            app.NormalizedDepthLowLimitEditField.Limits = [0 1000000000];
            app.HighlimitEditFieldLabel = uilabel(app.NormalizeddepthlimitscalculationsPanel);
            app.HighlimitEditFieldLabel.Text = 'High limit';
            app.NormalizedDepthHighLimitEditField = uieditfield(app.NormalizeddepthlimitscalculationsPanel, 'numeric');
            app.NormalizedDepthHighLimitEditField.Limits = [0 1000000000];
            app.NormalizedDepthHighLimitEditField.Tooltip = {'If this limit is set to inf, then the maximum value of normalized depth will be used as the high limit.'};
            app.NormalizedDepthHighLimitEditField.Value = 0.2;
            app.OKButton = uibutton(app.DPParametersUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKCallback, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.DPParametersUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCallback, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.DPParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DPParameters(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DPParametersUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.DPParametersUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DPParametersUIFigure)
        end
    end
end