classdef FileListParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                      matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        LastImageFullNameEditField      matlab.ui.control.EditField
        FirstImageFullNameEditField     matlab.ui.control.EditField
        FileNamesRootEditField          matlab.ui.control.EditField
        NumberOfDigitsEditField         matlab.ui.control.NumericEditField
        NumberOfImagesEditField         matlab.ui.control.NumericEditField
        FirstImageNumberEditField       matlab.ui.control.NumericEditField
        ImagesFormatDropDown            matlab.ui.control.DropDown
        AutomaticFilelistCheckBox       matlab.ui.control.CheckBox
        FirstimagenumberEditFieldLabel  matlab.ui.control.Label
        NumberofimagesEditFieldLabel    matlab.ui.control.Label
        NumberofdigitsEditFieldLabel    matlab.ui.control.Label
        LastimagefullnameLabel          matlab.ui.control.Label
        FirstimagefullnameEditFieldLabel  matlab.ui.control.Label
        ImagesformatDropDownLabel       matlab.ui.control.Label
        RootLabel                       matlab.ui.control.Label
    end

      
    properties (Access = private)
        automaticFilelist
        imgFormat

        root
        firstImgNb
        nbImages
        nbDigits

        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, automaticFilelist, root, firstImgNb, nbImages, nbDigits, imgFormat)
            % Get parameters from main app
            app.AutomaticFilelistCheckBox.Value = automaticFilelist;
            UseFilelistCallback(app)
            
            app.FileNamesRootEditField.Value = root;
            app.FirstImageNumberEditField.Value = firstImgNb;
            app.NumberOfImagesEditField.Value = nbImages;
            app.NumberOfDigitsEditField.Value = nbDigits;
            app.ImagesFormatDropDown.Value = imgFormat;
            
            % Initialize first/last image name
            FirstLastImageFullNameCallback(app)

            app.mainapp = mainapp;
        end

        % Button pushed function: OKButton
        function OKCallback(app, event)
            % Pass back parameters to main app
            app.mainapp.automaticFilelist = app.AutomaticFilelistCheckBox.Value;

            app.mainapp.root = app.FileNamesRootEditField.Value;
            app.mainapp.firstImgNb = app.FirstImageNumberEditField.Value;
            app.mainapp.nbImages = app.NumberOfImagesEditField.Value;
            app.mainapp.nbDigits = app.NumberOfDigitsEditField.Value;
            app.mainapp.imgFormat = app.ImagesFormatDropDown.Value;
            
            delete(app)
        end

        % Value changed function: FileNamesRootEditField, 
        % ...and 4 other components
        function FirstLastImageFullNameCallback(app, event)
            formatString = sprintf('%%0%dd',app.NumberOfDigitsEditField.Value);
            firstImageNumberAllDigits = sprintf(formatString,app.FirstImageNumberEditField.Value);
            app.FirstImageFullNameEditField.Value = sprintf('%s%s.%s',app.FileNamesRootEditField.Value, ...
                firstImageNumberAllDigits,app.ImagesFormatDropDown.Value);
            lastImageNumberAllDigits = sprintf(formatString,app.FirstImageNumberEditField.Value+app.NumberOfImagesEditField.Value-1);
            app.LastImageFullNameEditField.Value = sprintf('%s%s.%s',app.FileNamesRootEditField.Value, ...
                lastImageNumberAllDigits,app.ImagesFormatDropDown.Value);
        end

        % Button pushed function: CancelButton
        function CancelCallback(app, event)
            delete(app)
        end

        % Value changed function: AutomaticFilelistCheckBox
        function UseFilelistCallback(app, event)
            if app.AutomaticFilelistCheckBox.Value == false
                app.FileNamesRootEditField.Enable = "on";
                app.RootLabel.Enable = "on";
                
                app.FirstImageNumberEditField.Enable = "on";
                app.FirstimagenumberEditFieldLabel.Enable = "on";

                app.NumberOfImagesEditField.Enable = "on";
                app.NumberofimagesEditFieldLabel.Enable = "on";

                app.NumberOfDigitsEditField.Enable = "on";
                app.NumberofdigitsEditFieldLabel.Enable = "on";

                app.FirstImageFullNameEditField.Enable = "on";
                app.FirstimagefullnameEditFieldLabel.Enable = "on";

                app.LastImageFullNameEditField.Enable = "on";
                app.LastimagefullnameLabel.Enable = "on";
            else
                app.FileNamesRootEditField.Enable = "off";
                app.RootLabel.Enable = "off";
                
                app.FirstImageNumberEditField.Enable = "off";
                app.FirstimagenumberEditFieldLabel.Enable = "off";

                app.NumberOfImagesEditField.Enable = "off";
                app.NumberofimagesEditFieldLabel.Enable = "off";

                app.NumberOfDigitsEditField.Enable = "off";
                app.NumberofdigitsEditFieldLabel.Enable = "off";

                app.FirstImageFullNameEditField.Enable = "off";
                app.FirstimagefullnameEditFieldLabel.Enable = "off";

                app.LastImageFullNameEditField.Enable = "off";
                app.LastimagefullnameLabel.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'File list parameters';
            app.MainWindow.Icon = fullfile(pathToMLAPP, '..', 'rsdp_resources', 'icon-rsdp.png');
            app.RootLabel = uilabel(app.MainWindow);
            app.RootLabel.Enable = 'off';
            app.RootLabel.Text = 'Root';
            app.ImagesformatDropDownLabel = uilabel(app.MainWindow);
            app.ImagesformatDropDownLabel.Text = 'Images format';
            app.FirstimagefullnameEditFieldLabel = uilabel(app.MainWindow);
            app.FirstimagefullnameEditFieldLabel.Enable = 'off';
            app.FirstimagefullnameEditFieldLabel.Text = 'First image full name';
            app.LastimagefullnameLabel = uilabel(app.MainWindow);
            app.LastimagefullnameLabel.Enable = 'off';
            app.LastimagefullnameLabel.Text = 'Last image full name';
            app.NumberofdigitsEditFieldLabel = uilabel(app.MainWindow);
            app.NumberofdigitsEditFieldLabel.Enable = 'off';
            app.NumberofdigitsEditFieldLabel.Text = 'Number of digits';
            app.NumberofimagesEditFieldLabel = uilabel(app.MainWindow);
            app.NumberofimagesEditFieldLabel.Enable = 'off';
            app.NumberofimagesEditFieldLabel.Text = 'Number of images';
            app.FirstimagenumberEditFieldLabel = uilabel(app.MainWindow);
            app.FirstimagenumberEditFieldLabel.Enable = 'off';
            app.FirstimagenumberEditFieldLabel.Text = 'First image number';
            app.AutomaticFilelistCheckBox = uicheckbox(app.MainWindow);
            app.AutomaticFilelistCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFilelistCallback, true);
            app.AutomaticFilelistCheckBox.Tooltip = {'If this box is checked, then all the images with the specifed format will be selected in the file list.'};
            app.AutomaticFilelistCheckBox.Text = 'Automatic file list';
            app.AutomaticFilelistCheckBox.Value = true;
            app.ImagesFormatDropDown = uidropdown(app.MainWindow);
            app.ImagesFormatDropDown.Items = {'tif', 'tiff', 'bmp', 'jpg', 'jpeg', 'png'};
            app.ImagesFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @FirstLastImageFullNameCallback, true);
            app.ImagesFormatDropDown.Value = 'tif';
            app.FirstImageNumberEditField = uieditfield(app.MainWindow, 'numeric');
            app.FirstImageNumberEditField.ValueChangedFcn = createCallbackFcn(app, @FirstLastImageFullNameCallback, true);
            app.FirstImageNumberEditField.Enable = 'off';
            app.NumberOfImagesEditField = uieditfield(app.MainWindow, 'numeric');
            app.NumberOfImagesEditField.ValueChangedFcn = createCallbackFcn(app, @FirstLastImageFullNameCallback, true);
            app.NumberOfImagesEditField.Enable = 'off';
            app.NumberOfImagesEditField.Value = 20;
            app.NumberOfDigitsEditField = uieditfield(app.MainWindow, 'numeric');
            app.NumberOfDigitsEditField.ValueChangedFcn = createCallbackFcn(app, @FirstLastImageFullNameCallback, true);
            app.NumberOfDigitsEditField.Enable = 'off';
            app.NumberOfDigitsEditField.Value = 3;
            app.FileNamesRootEditField = uieditfield(app.MainWindow, 'text');
            app.FileNamesRootEditField.ValueChangedFcn = createCallbackFcn(app, @FirstLastImageFullNameCallback, true);
            app.FileNamesRootEditField.Enable = 'off';
            app.FirstImageFullNameEditField = uieditfield(app.MainWindow, 'text');
            app.FirstImageFullNameEditField.Editable = 'off';
            app.FirstImageFullNameEditField.Enable = 'off';
            app.FirstImageFullNameEditField.Value = '000.tif';
            app.LastImageFullNameEditField = uieditfield(app.MainWindow, 'text');
            app.LastImageFullNameEditField.Editable = 'off';
            app.LastImageFullNameEditField.Enable = 'off';
            app.LastImageFullNameEditField.Value = '019.tif';
            app.OKButton = uibutton(app.MainWindow, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKCallback, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.MainWindow, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCallback, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FileListParameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end