classdef ImageProcessingParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                 matlab.ui.Figure
        CancelButton               matlab.ui.control.Button
        OKButton                   matlab.ui.control.Button
        ImagesformatDropDownLabel  matlab.ui.control.Label
        FiltersPanel               matlab.ui.container.Panel
        NbImagesStepLabel          matlab.ui.control.Label
        NbImagesStepSpinner        matlab.ui.control.Spinner
        Filter2DropDown            matlab.ui.control.DropDown
        Filter1DropDown            matlab.ui.control.DropDown
        Filter2DropDownLabel       matlab.ui.control.Label
        Filter1DropDownLabel       matlab.ui.control.Label
        RotationPanel              matlab.ui.container.Panel
        ImageRotationEditField     matlab.ui.control.DropDown
        RotationoftheimagesclockwiseLabel  matlab.ui.control.Label
        CropPanel                  matlab.ui.container.Panel
        ImageWidthLabel            matlab.ui.control.Label
        ImageHeightEditField       matlab.ui.control.NumericEditField
        ImageWidthEditField        matlab.ui.control.NumericEditField
        CropCheckBox               matlab.ui.control.CheckBox
        ImageHeightLabel           matlab.ui.control.Label
        ImagesFormatDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        imageWidth
        imageHeight
        
        imgRotation

        filter1
        filter2
        
        nbImgsStep

        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, imgFormat, cropOption, imageWidth, imageHeight, imgRotation, filter1, filter2, nbImagesStep)
            % Retrieve parameters from main app
            app.ImagesFormatDropDown.Value = imgFormat;

            app.CropCheckBox.Value = cropOption;
            app.ImageWidthEditField.Value = imageWidth;
            app.ImageHeightEditField.Value = imageHeight;

            app.imgRotation = imgRotation;
            if imgRotation == 0
                app.ImageRotationEditField.Value = "None";
            elseif imgRotation == 45
                app.ImageRotationEditField.Value = "45°";
            else
                app.ImageRotationEditField.Value = "90°";
            end

            app.Filter1DropDown.Value = filter1;
            app.Filter2DropDown.Value = filter2;
            
            app.NbImagesStepSpinner.Value = nbImagesStep;

            FiltersOptionsCallback(app)

            app.mainapp = mainapp;
        end

        % Button pushed function: OKButton
        function OKCallback(app, event)
            app.mainapp.imgFormat = app.ImagesFormatDropDown.Value;
            
            app.mainapp.cropOption = app.CropCheckBox.Value;
            app.mainapp.imageWidth = app.ImageWidthEditField.Value;
            app.mainapp.imageHeight = app.ImageHeightEditField.Value;
            
            if app.ImageRotationEditField.Value == "None"
                app.mainapp.imgRotation = 0;
            elseif app.ImageRotationEditField.Value == "45°"
                app.mainapp.imgRotation = 45;
            else
                app.mainapp.imgRotation = 90;
            end

            app.mainapp.filter1 = app.Filter1DropDown.Value;
            app.mainapp.filter2 = app.Filter2DropDown.Value;

            app.mainapp.nbImagesStep = app.NbImagesStepSpinner.Value;
            
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelCallback(app, event)
            delete(app)
        end

        % Value changed function: Filter1DropDown, Filter2DropDown
        function FiltersOptionsCallback(app, event)
            if app.Filter1DropDown.Value == "Intensity averaging" || app.Filter2DropDown.Value == "Intensity averaging"
                app.NbImagesStepLabel.Enable = "on";
                app.NbImagesStepSpinner.Enable = "on";
            else
                app.NbImagesStepLabel.Enable = "off";
                app.NbImagesStepSpinner.Enable = "off";
            end
        end

        % Value changed function: CropCheckBox
        function CropCallback(app, event)
            if app.CropCheckBox.Value == true
                app.ImageWidthLabel.Enable = "on";
                app.ImageWidthEditField.Enable = "on";

                app.ImageHeightLabel.Enable = "on";
                app.ImageHeightEditField.Enable = "on";
            else
                app.ImageWidthLabel.Enable = "off";
                app.ImageWidthEditField.Enable = "off";

                app.ImageHeightLabel.Enable = "off";
                app.ImageHeightEditField.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Image processing parameters';
            app.MainWindow.Icon = fullfile(pathToMLAPP, '..', 'rsdp_resources', 'icon-rsdp.png');
            app.ImagesFormatDropDown = uidropdown(app.MainWindow);
            app.ImagesFormatDropDown.Items = {'tif', 'tiff', 'bmp', 'jpg', 'jpeg', 'png'};
            app.ImagesFormatDropDown.Tooltip = {'The processing will be applied to all images with the given file format.'};
            app.ImagesFormatDropDown.Value = 'tif';
            app.CropPanel = uipanel(app.MainWindow);
            app.CropPanel.Tooltip = {'Cropping to remove the bottom label from SEM images, if necessary.'};
            app.CropPanel.Title = 'Step 1: Crop';
            app.ImageHeightLabel = uilabel(app.CropPanel);
            app.ImageHeightLabel.Text = 'Image height (px)';
            app.CropCheckBox = uicheckbox(app.CropPanel);
            app.CropCheckBox.ValueChangedFcn = createCallbackFcn(app, @CropCallback, true);
            app.CropCheckBox.Text = 'Crop?';
            app.CropCheckBox.Value = true;
            app.ImageWidthEditField = uieditfield(app.CropPanel, 'numeric');
            app.ImageWidthEditField.Limits = [0 Inf];
            app.ImageWidthEditField.Value = 1024;
            app.ImageHeightEditField = uieditfield(app.CropPanel, 'numeric');
            app.ImageHeightEditField.Limits = [0 Inf];
            app.ImageHeightEditField.Value = 884;
            app.ImageWidthLabel = uilabel(app.CropPanel);
            app.ImageWidthLabel.Text = 'Image width (px)';
            app.RotationPanel = uipanel(app.MainWindow);
            app.RotationPanel.Tooltip = {'Rotating to create new images markers analysis.'};
            app.RotationPanel.Title = 'Step 2: Rotation';
            app.RotationoftheimagesclockwiseLabel = uilabel(app.RotationPanel);
            app.RotationoftheimagesclockwiseLabel.Text = 'Rotation of the images (clockwise)';
            app.ImageRotationEditField = uidropdown(app.RotationPanel);
            app.ImageRotationEditField.Items = {'None', '45°', '90°'};
            app.ImageRotationEditField.Value = 'None';
            app.FiltersPanel = uipanel(app.MainWindow);
            app.FiltersPanel.Title = 'Step 3: Apply filters';
            app.Filter1DropDownLabel = uilabel(app.FiltersPanel);
            app.Filter1DropDownLabel.Text = 'Filter 1';
            app.Filter2DropDownLabel = uilabel(app.FiltersPanel);
            app.Filter2DropDownLabel.Text = 'Filter 2';
            app.Filter1DropDown = uidropdown(app.FiltersPanel);
            app.Filter1DropDown.Items = {'Image registration (alignment)', 'Intensity averaging', 'None'};
            app.Filter1DropDown.ValueChangedFcn = createCallbackFcn(app, @FiltersOptionsCallback, true);
            app.Filter1DropDown.Value = 'Image registration (alignment)';
            app.Filter2DropDown = uidropdown(app.FiltersPanel);
            app.Filter2DropDown.Items = {'Image registration (alignment)', 'Intensity averaging', 'None'};
            app.Filter2DropDown.ValueChangedFcn = createCallbackFcn(app, @FiltersOptionsCallback, true);
            app.Filter2DropDown.Value = 'Intensity averaging';
            app.NbImagesStepSpinner = uispinner(app.FiltersPanel);
            app.NbImagesStepSpinner.Limits = [1 100];
            app.NbImagesStepSpinner.Value = 5;
            app.NbImagesStepLabel = uilabel(app.FiltersPanel);
            app.NbImagesStepLabel.Text = 'Number of images per milling step';
            app.ImagesformatDropDownLabel = uilabel(app.MainWindow);
            app.ImagesformatDropDownLabel.Text = 'Images format';
            app.OKButton = uibutton(app.MainWindow, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKCallback, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.MainWindow, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCallback, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageProcessingParameters(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainWindow)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MainWindow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end