classdef ProcessCorrelationsParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                     matlab.ui.Figure
        CancelButton                   matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        DisplacementTypeDropDown       matlab.ui.control.DropDown
        ReferenceImageDropDown         matlab.ui.control.DropDown
        ProcessingModeDropDown         matlab.ui.control.DropDown
        ReductionFactorEditField       matlab.ui.control.NumericEditField
        CorrelationSizeEditField       matlab.ui.control.NumericEditField
        DisplacementtypeDropDownLabel  matlab.ui.control.Label
        ReductionfactorEditFieldLabel  matlab.ui.control.Label
        ReferenceimageDropDownLabel    matlab.ui.control.Label
        ProcessingmodeDropDownLabel    matlab.ui.control.Label
        CorrelationsizeEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        corrSize
        redFactor
        processMode
        refImage
        dispType

        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, corrSize, redFactor, processMode, refImage, dispType)
            app.CorrelationSizeEditField.Value = corrSize;
            app.ReductionFactorEditField.Value = redFactor;
            app.ProcessingModeDropDown.Value = processMode;
            app.ReferenceImageDropDown.Value = refImage;
            app.DisplacementTypeDropDown.Value = dispType;
            
            app.mainapp = mainapp;
        end

        % Button pushed function: OKButton
        function OKCallback(app, event)
            app.mainapp.corrSize = app.CorrelationSizeEditField.Value;
            app.mainapp.redFactor = app.ReductionFactorEditField.Value;
            app.mainapp.processMode = app.ProcessingModeDropDown.Value;
            app.mainapp.refImage = app.ReferenceImageDropDown.Value;
            app.mainapp.dispType = app.DisplacementTypeDropDown.Value;

            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelCallback(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Process correlations parameters';
            app.MainWindow.Icon = fullfile(pathToMLAPP, '..', 'rsdp_resources', 'icon-rsdp.png');
            app.CorrelationsizeEditFieldLabel = uilabel(app.MainWindow);
            app.CorrelationsizeEditFieldLabel.Text = 'Correlation size';
            app.ProcessingmodeDropDownLabel = uilabel(app.MainWindow);
            app.ProcessingmodeDropDownLabel.Text = 'Processing mode';
            app.ReferenceimageDropDownLabel = uilabel(app.MainWindow);
            app.ReferenceimageDropDownLabel.Text = 'Reference image';
            app.ReductionfactorEditFieldLabel = uilabel(app.MainWindow);
            app.ReductionfactorEditFieldLabel.Text = 'Reduction factor';
            app.DisplacementtypeDropDownLabel = uilabel(app.MainWindow);
            app.DisplacementtypeDropDownLabel.Text = 'Displacement type';
            app.CorrelationSizeEditField = uieditfield(app.MainWindow, 'numeric');
            app.CorrelationSizeEditField.Value = 15;
            app.ReductionFactorEditField = uieditfield(app.MainWindow, 'numeric');
            app.ReductionFactorEditField.Value = 15;
            app.ProcessingModeDropDown = uidropdown(app.MainWindow);
            app.ProcessingModeDropDown.Items = {'Single', 'Multi', 'GPU'};
            app.ProcessingModeDropDown.Value = 'Single';
            app.ReferenceImageDropDown = uidropdown(app.MainWindow);
            app.ReferenceImageDropDown.Items = {'First', 'Previous'};
            app.ReferenceImageDropDown.Value = 'First';
            app.DisplacementTypeDropDown = uidropdown(app.MainWindow);
            app.DisplacementTypeDropDown.Items = {'Large displacements (vectorial)', 'Large displacements (scalar)', 'Small displacements'};
            app.DisplacementTypeDropDown.Value = 'Large displacements (vectorial)';
            app.OKButton = uibutton(app.MainWindow, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKCallback, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.MainWindow, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCallback, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProcessCorrelationsParameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end