classdef AveragingParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                      matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        DisplayIndividualPlotsCheckbox  matlab.ui.control.CheckBox
        NormalizeddepthlimitscalculationsPanel  matlab.ui.container.Panel
        NormalizedDepthHighLimitEditField  matlab.ui.control.NumericEditField
        HighlimitEditFieldLabel         matlab.ui.control.Label
        NormalizedDepthLowLimitEditField  matlab.ui.control.NumericEditField
        LowlimitEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        normalizedDepthLowLimit
        normalizedDepthHighLimit
        displayIndividualPlots

        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, displayIndividualPlots, normalizedDepthLowLimit, normalizedDepthHighLimit)
            app.NormalizedDepthLowLimitEditField.Value = normalizedDepthLowLimit;
            app.NormalizedDepthHighLimitEditField.Value = normalizedDepthHighLimit;
            app.DisplayIndividualPlotsCheckbox.Value = displayIndividualPlots;
            app.mainapp = mainapp;
        end

        % Button pushed function: OKButton
        function OKCallback(app, event)
            app.mainapp.normalizedDepthLowLimit = app.NormalizedDepthLowLimitEditField.Value;
            app.mainapp.normalizedDepthHighLimit = app.NormalizedDepthHighLimitEditField.Value;
            app.mainapp.displayIndividualPlots = app.DisplayIndividualPlotsCheckbox.Value;

            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelCallback(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Averaging parameters';
            app.MainWindow.Icon = fullfile(pathToMLAPP, 'DP_app_resources', 'icon-DP_app.png');
            app.NormalizeddepthlimitscalculationsPanel = uipanel(app.MainWindow);
            app.NormalizeddepthlimitscalculationsPanel.Tooltip = {'Those values represent the interval where values of normalized depth will be interpolated before the calculation of stress.'};
            app.NormalizeddepthlimitscalculationsPanel.Title = 'Normalized depth limits (calculations)';
            app.LowlimitEditFieldLabel = uilabel(app.NormalizeddepthlimitscalculationsPanel);
            app.LowlimitEditFieldLabel.Text = 'Low limit';
            app.NormalizedDepthLowLimitEditField = uieditfield(app.NormalizeddepthlimitscalculationsPanel, 'numeric');
            app.NormalizedDepthLowLimitEditField.Limits = [0 Inf];
            app.HighlimitEditFieldLabel = uilabel(app.NormalizeddepthlimitscalculationsPanel);
            app.HighlimitEditFieldLabel.Text = 'High limit';
            app.NormalizedDepthHighLimitEditField = uieditfield(app.NormalizeddepthlimitscalculationsPanel, 'numeric');
            app.NormalizedDepthHighLimitEditField.Limits = [0 Inf];
            app.NormalizedDepthHighLimitEditField.Tooltip = {'If this limit is set to inf, then the maximum value of normalized depth will be used as the high limit.'};
            app.NormalizedDepthHighLimitEditField.Value = Inf;
            app.DisplayIndividualPlotsCheckbox = uicheckbox(app.MainWindow);
            app.DisplayIndividualPlotsCheckbox.Text = 'Display individual plots';
            app.OKButton = uibutton(app.MainWindow, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKCallback, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.MainWindow, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCallback, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AveragingParameters(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainWindow)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MainWindow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end