classdef CompareSamplesParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                     matlab.ui.Figure
        CancelButton                   matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        ComparisonFilenameEditField    matlab.ui.control.EditField
        ComparisonFilenameEditFieldLabel  matlab.ui.control.Label
        BrowseSavingDirectoryButton    matlab.ui.control.Button
        SavingDirectoryEditField       matlab.ui.control.EditField
        SavingDirectoryEditFieldLabel  matlab.ui.control.Label
        PillarDiameterEditField        matlab.ui.control.NumericEditField
        PillarDiameterLabel            matlab.ui.control.Label
        FullComparisonOptions          matlab.ui.container.ButtonGroup
        CombinedPlotsButton            matlab.ui.control.RadioButton
        SuccessivePlotsButton          matlab.ui.control.RadioButton
        ComparisonType                 matlab.ui.container.ButtonGroup
        OnePillarComparisonButton      matlab.ui.control.RadioButton
        FullComparisonButton           matlab.ui.control.RadioButton
        Plot2NameEditField             matlab.ui.control.EditField
        Plot2NameLabel                 matlab.ui.control.Label
        Plot1NameEditField             matlab.ui.control.EditField
        Plot1NameLabel                 matlab.ui.control.Label
        BrowseSample2Button            matlab.ui.control.Button
        Sample2PathEditField           matlab.ui.control.EditField
        Sample2PathLabel               matlab.ui.control.Label
        BrowseSample1Button            matlab.ui.control.Button
        Sample1PathEditField           matlab.ui.control.EditField
        Sample1PathLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        savingStatus

        sample1Path
        sample2Path
        plot1Name
        plot2Name
        fullComparisonOption
        stringingMode
        pillarDiameter
        comparisonSavingPath
        comparisonFileName

        mainapp
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, savingStatus, sample1Path, sample2Path, plot1Name, plot2Name, fullComparisonOption, stringingMode, pillarDiameter, comparisonSavingPath, comparisonFileName)
            % disp(sample1Path)
            app.sample1Path = sample1Path;
            if ~isempty(app.sample1Path)
                [~,app.Sample1PathEditField.Value] = fileparts(app.sample1Path);
            end

            app.sample2Path = sample2Path;
            if ~isempty(app.sample2Path)
                [~,app.Sample2PathEditField.Value] = fileparts(app.sample2Path);
            end
            
            app.Plot1NameEditField.Value = plot1Name;
            app.Plot2NameEditField.Value = plot2Name;

            if strcmp(stringingMode,"Combined")
                app.CombinedPlotsButton.Value = true;
            else
                app.SuccessivePlotsButton.Value = true;
            end
            
            app.FullComparisonButton.Value = fullComparisonOption;
           
            app.PillarDiameterEditField.Value = pillarDiameter;

            app.comparisonSavingPath = comparisonSavingPath;
            if ~isempty(app.comparisonSavingPath)
                [~,app.SavingDirectoryEditField.Value] = fileparts(app.comparisonSavingPath);
            end

            app.ComparisonFilenameEditField.Value = comparisonFileName;

            ComparisonTypeCallback(app)
            
            app.savingStatus = savingStatus;
            if app.savingStatus == true
                app.ComparisonFilenameEditFieldLabel.Enable = "on";
                app.ComparisonFilenameEditField.Enable = "on";
                app.BrowseSavingDirectoryButton.Enable = "on";
                app.SavingDirectoryEditFieldLabel.Enable = "on";
                app.SavingDirectoryEditField.Enable = "on";
            else
                app.ComparisonFilenameEditFieldLabel.Enable = "off";
                app.ComparisonFilenameEditField.Enable = "off";
                app.BrowseSavingDirectoryButton.Enable = "off";
                app.SavingDirectoryEditFieldLabel.Enable = "off";
                app.SavingDirectoryEditField.Enable = "off";
            end

            app.mainapp = mainapp;
        end

        % Button pushed function: CancelButton
        function CancelCallback(app, event)
            delete(app)
        end

        % Button pushed function: OKButton
        function OKCallback(app, event)
            app.mainapp.sample1Path = app.sample1Path;
            app.mainapp.sample2Path = app.sample2Path;

            app.mainapp.plot1Name = app.Plot1NameEditField.Value;
            app.mainapp.plot2Name = app.Plot2NameEditField.Value;
            if app.CombinedPlotsButton.Value == true
                app.mainapp.stringingMode = "Combined";
            else
                app.mainapp.stringingMode = "Successive";
            end
            app.mainapp.fullComparisonOption = app.FullComparisonButton.Value;
            app.mainapp.pillarDiameter = app.PillarDiameterEditField.Value;
            app.mainapp.comparisonSavingPath = app.comparisonSavingPath;
            app.mainapp.comparisonFileName = app.ComparisonFilenameEditField.Value;

            delete(app)
        end

        % Button pushed function: BrowseSample1Button
        function BrowseSample1Callback(app, event)
            app.sample1Path = uigetdir('', "Select the series directory.");

            if app.sample1Path == 0 | isempty(app.sample1Path)
                disp('No directory selected.')
                return
            end
            % [~, app.sample1Name] = fileparts(app.sample1Path);
            % app.Sample1PathEditField.Value = app.sample1Path;
            [~,app.Sample1PathEditField.Value] = fileparts(app.sample1Path);
            % app.Plot1NameEditField.Value = app.sample1Name;
        end

        % Button pushed function: BrowseSample2Button
        function BrowseSample2Callback(app, event)
            app.sample2Path = uigetdir('', "Select the series directory.");

            if app.sample2Path == 0 | isempty(app.sample2Path)
                disp('No directory selected.')
                return
            end
            % [~, app.sample2Name] = fileparts(app.sample2Path);
            % app.Sample2PathEditField.Value = app.sample2Path;
            [~,app.Sample2PathEditField.Value] = fileparts(app.sample2Path);
            % app.Plot2NameEditField.Value = app.sample2Name;
        end

        % Selection changed function: ComparisonType
        function ComparisonTypeCallback(app, event)
            if app.FullComparisonButton.Value == true
                app.PillarDiameterLabel.Enable = "off";
                app.PillarDiameterEditField.Enable = "off";

                app.FullComparisonOptions.Enable = "on";
                app.SuccessivePlotsButton.Enable = "on";
                app.CombinedPlotsButton.Enable = "on";
            else
                app.PillarDiameterLabel = "on";
                app.PillarDiameterEditField.Enable = "on";

                app.FullComparisonOptions.Enable = "off";
                app.SuccessivePlotsButton.Enable = "off";
                app.CombinedPlotsButton.Enable = "off";
            end
        end

        % Button pushed function: BrowseSavingDirectoryButton
        function BrowseSavingDirectoryCallback(app, event)
            app.comparisonSavingPath = uigetdir('', "Select the series directory.");

            if app.comparisonSavingPath == 0 | isempty(app.comparisonSavingPath)
                disp('No directory selected.')
                return
            end
            % [~, app.comparisonFileName] = fileparts(app.comparisonSavingPath);
            % app.ComparisonFilenameEditField.Value = app.comparisonFileName;
            [~,app.SavingDirectoryEditField.Value] = fileparts(app.comparisonSavingPath);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Comparison Parameters';
            app.MainWindow.Icon = fullfile(pathToMLAPP, 'DP_app_resources', 'icon-DP_app.png');
            app.Sample1PathLabel = uilabel(app.MainWindow);
            app.Sample1PathLabel.Text = 'Sample 1 path';
            app.Sample1PathEditField = uieditfield(app.MainWindow, 'text');
            app.Sample1PathEditField.Editable = 'off';
            app.BrowseSample1Button = uibutton(app.MainWindow, 'push');
            app.BrowseSample1Button.ButtonPushedFcn = createCallbackFcn(app, @BrowseSample1Callback, true);
            app.BrowseSample1Button.Text = 'Browse...';
            app.Sample2PathLabel = uilabel(app.MainWindow);
            app.Sample2PathLabel.Text = 'Sample 2 path';
            app.Sample2PathEditField = uieditfield(app.MainWindow, 'text');
            app.Sample2PathEditField.Editable = 'off';
            app.BrowseSample2Button = uibutton(app.MainWindow, 'push');
            app.BrowseSample2Button.ButtonPushedFcn = createCallbackFcn(app, @BrowseSample2Callback, true);
            app.BrowseSample2Button.Text = 'Browse...';
            app.Plot1NameLabel = uilabel(app.MainWindow);
            app.Plot1NameLabel.Text = 'Plot 1 name';
            app.Plot1NameEditField = uieditfield(app.MainWindow, 'text');
            app.Plot1NameEditField.Placeholder = 'Sample 1';
            app.Plot2NameLabel = uilabel(app.MainWindow);
            app.Plot2NameLabel.Text = 'Plot 2 name';
            app.Plot2NameEditField = uieditfield(app.MainWindow, 'text');
            app.Plot2NameEditField.Placeholder = 'Sample 2';
            app.ComparisonType = uibuttongroup(app.MainWindow);
            app.ComparisonType.SelectionChangedFcn = createCallbackFcn(app, @ComparisonTypeCallback, true);
            app.ComparisonType.Title = 'Comparison type';
            app.FullComparisonButton = uiradiobutton(app.ComparisonType);
            app.FullComparisonButton.Tooltip = {'Successive plots for each pillar diameter'};
            app.FullComparisonButton.Text = 'Full comparison';
            app.OnePillarComparisonButton = uiradiobutton(app.ComparisonType);
            app.OnePillarComparisonButton.Tooltip = {'Combination of all the data together in one plot'};
            app.OnePillarComparisonButton.Text = '1 pillar';
            app.OnePillarComparisonButton.Value = true;
            app.FullComparisonOptions = uibuttongroup(app.MainWindow);
            app.FullComparisonOptions.Title = 'Options for full comparison';
            app.SuccessivePlotsButton = uiradiobutton(app.FullComparisonOptions);
            app.SuccessivePlotsButton.Tooltip = {'Successive plots for each pillar diameter'};
            app.SuccessivePlotsButton.Text = 'Succession';
            app.SuccessivePlotsButton.Value = true;
            app.CombinedPlotsButton = uiradiobutton(app.FullComparisonOptions);
            app.CombinedPlotsButton.Tooltip = {'Combination of all the data together in one plot'};
            app.CombinedPlotsButton.Text = 'Combination';
            app.PillarDiameterLabel = uilabel(app.MainWindow);
            app.PillarDiameterLabel.Text = 'Pillar diameter ';
            app.PillarDiameterEditField = uieditfield(app.MainWindow, 'numeric');
            app.PillarDiameterEditField.Value = 10;
            app.SavingDirectoryEditFieldLabel = uilabel(app.MainWindow);
            app.SavingDirectoryEditFieldLabel.Text = 'Saving directory';
            app.SavingDirectoryEditField = uieditfield(app.MainWindow, 'text');
            app.SavingDirectoryEditField.Editable = 'off';
            app.BrowseSavingDirectoryButton = uibutton(app.MainWindow, 'push');
            app.BrowseSavingDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseSavingDirectoryCallback, true);
            app.BrowseSavingDirectoryButton.Text = 'Browse...';
            app.ComparisonFilenameEditFieldLabel = uilabel(app.MainWindow);
            app.ComparisonFilenameEditFieldLabel.Text = 'File name';
            app.ComparisonFilenameEditField = uieditfield(app.MainWindow, 'text');
            app.ComparisonFilenameEditField.Placeholder = 'comparison-sample1_sample2';
            app.OKButton = uibutton(app.MainWindow, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKCallback, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.MainWindow, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCallback, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CompareSamplesParameters(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainWindow)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MainWindow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end