classdef CleanMarkersStdDev_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1  matlab.ui.Figure
        Max2     matlab.ui.control.Label
        EdtMax2  matlab.ui.control.EditField
        Cur2     matlab.ui.control.Label
        EdtCur2  matlab.ui.control.EditField
        Min2     matlab.ui.control.Label
        EdtMin2  matlab.ui.control.EditField
        Max1     matlab.ui.control.Label
        EdtMax1  matlab.ui.control.EditField
        Cur1     matlab.ui.control.Label
        EdtCur1  matlab.ui.control.EditField
        Min1     matlab.ui.control.Label
        EdtMin1  matlab.ui.control.EditField
        Apply    matlab.ui.control.Button
        slider2  matlab.ui.control.Slider
        slider1  matlab.ui.control.Slider
        axes2    matlab.ui.control.UIAxes
        axes1    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function AdaptThresholding(~, hObject, handles)
            % TODO: apply thresholding to each image separately when markers can be handled independly of image
            
            
                % Select markers by threshold
                MedianStd1=median(handles.Std1,2);
                MedianStd2=median(handles.Std2,2);
            
                Slider1Value=get(handles.slider1,'Value');
                Slider2Value=get(handles.slider2,'Value');
                Max1Value=str2double(get(handles.EdtMax1,'String'));
                Max2Value=str2double(get(handles.EdtMax2,'String'));
                Centers1=0:Max1Value/50:Max1Value;
                Centers2=0:Max2Value/50:Max2Value;
            
                % Find all outliers by threshold
                Selection1=MedianStd1<Slider1Value;
                Plot1=MedianStd1(Selection1);
                Selection2=MedianStd2<Slider2Value;
                Plot2=MedianStd2(Selection2);
                OutlierRows1=find(MedianStd1>Slider1Value);
                OutlierRows2=find(MedianStd2>Slider2Value);
                RowRemoval=unique([OutlierRows1;OutlierRows2]);
            
                handles.TempStd1=handles.Std1;
                handles.TempStd2=handles.Std2;
                handles.TempValid1=handles.Valid1;
                handles.TempValid2=handles.Valid2;
                handles.TempCorrCoef=handles.CorrCoef;
            
                % Remove outliers and save temporary result
                handles.TempStd1(RowRemoval,:)=[];
                handles.TempStd2(RowRemoval,:)=[];
                handles.TempValid1(RowRemoval,:)=[];
                handles.TempValid2(RowRemoval,:)=[];
                handles.TempCorrCoef(RowRemoval,:)=[];
            
                % Captions
                switch(handles.Direction)
                    case 'x'
                        YLabel1='Std dev histogram x';
                        YLabel2='Std dev histogram y';
                    case 'y'
                        YLabel1='Std dev histogram y';
                        YLabel2='Std dev histogram x';
                    otherwise
                        return
                end
            
                % Plot histogram of remaining vs. outlier data (direction 1)
                axes(handles.axes1);
                [NumberOfElements1,CenterBins1]=hist(MedianStd1,Centers1);
                [NumberOfElements2,CenterBins2]=hist(Plot1,Centers1);
                bar(CenterBins1,NumberOfElements1,'r');
                hold on
                bar(CenterBins2,NumberOfElements2,'g');
                xlim([0,Max1Value]);
                ylabel(YLabel1);
                legend('Outliers','Remaining');
            
                % Plot histogram of remaining vs. outlier data (direction 2)
                axes(handles.axes2);
                [NumberOfElements1,CenterBins1]=hist(MedianStd2,Centers2);
                [NumberOfElements2,CenterBins2]=hist(Plot2,Centers2);
                bar(CenterBins1,NumberOfElements1,'r');
                hold on
                bar(CenterBins2,NumberOfElements2,'g');
                xlim([0,Max2Value]);
                ylabel(YLabel2);
                legend('Outliers','Remaining');
            
                guidata(hObject, handles);
        end
        
        function Exit_Callback(~, ~, ~, ~)
                uiresume();
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CleanMarkersStdDev_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
                % Choose default command line output for CleanMarkersStdDev
                handles.output = hObject;
                handles.Valid1 = varargin{1,1};
                handles.Valid2 = varargin{1,2};
                handles.Std1 = varargin{1,3};
                handles.Std2 = varargin{1,4};
                handles.CorrCoef = varargin{1,5};
                handles.Direction = varargin{1,6};
                handles.LogFileName = varargin{1,7};
            
                MedianStd1=median(handles.Std1,2);
                MedianMedianStd1=median(MedianStd1);
                MedianStd2=median(handles.Std2,2);
                MedianMedianStd2=median(MedianStd2);
                CurValueMedianStd1=round(1.5*MedianMedianStd1*100)/100;
                MaxValueMedianStd1=round(4*MedianMedianStd1*100)/100;
                CurValueMedianStd2=round(1.5*MedianMedianStd2*100)/100;
                MaxValueMedianStd2=round(4*MedianMedianStd2*100)/100;
            
                set(handles.slider1,'Min',0);
                set(handles.slider1,'Value',CurValueMedianStd1);
                set(handles.slider1,'Max',MaxValueMedianStd1);
                set(handles.EdtMin1,'String',num2str(0));
                set(handles.EdtMin1,'Enable','off');
                set(handles.EdtCur1,'String',num2str(CurValueMedianStd1));
                set(handles.EdtCur1,'Enable','off');
                set(handles.EdtMax1,'String',num2str(MaxValueMedianStd1));
                set(handles.EdtMax1,'Enable','off');
                set(handles.slider2,'Min',0);
                set(handles.slider2,'Value',CurValueMedianStd2);
                set(handles.slider2,'Max',MaxValueMedianStd2);
                set(handles.EdtMin2,'String',num2str(0));
                set(handles.EdtMin2,'Enable','off');
                set(handles.EdtCur2,'String',num2str(CurValueMedianStd2));
                set(handles.EdtCur2,'Enable','off');
                set(handles.EdtMax2,'String',num2str(MaxValueMedianStd2));
                set(handles.EdtMax2,'Enable','off');
                guidata(hObject, handles);
            
                AdaptThresholding(app, hObject, handles);
            
                % UIWAIT makes CleanMarkersStdDev wait for user response (see UIRESUME)
                set(handles.figure1,'CloseRequestFcn',@app.Exit_Callback);
                uiwait(handles.figure1);
        end

        % Button pushed function: Apply
        function Apply_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                Slider1Value=get(handles.slider1,'Value');
                Slider2Value=get(handles.slider2,'Value');
                WriteToLogFile(handles.LogFileName,'Slider 1',Slider1Value,'f');
                WriteToLogFile(handles.LogFileName,'Slider 2',Slider2Value,'f');
            
                % Apply temporary result before closing dialog
                handles.Valid1=handles.TempValid1;
                handles.Valid2=handles.TempValid2;
                handles.Std1=handles.TempStd1;
                handles.Std2=handles.TempStd2;
                handles.CorrCoef=handles.TempCorrCoef;
                guidata(hObject,handles);
                close(handles.figure1);
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                Slider1Value=get(handles.slider1,'Value');
                Slider1Value=round(Slider1Value*100)/100;
                set(handles.EdtCur1,'String',Slider1Value);
                AdaptThresholding(app, hObject,handles);
                guidata(hObject, handles);
        end

        % Value changed function: slider2
        function slider2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                Slider2Value=get(handles.slider2,'Value');
                Slider2Value=round(Slider2Value*100)/100;
                set(handles.EdtCur2,'String',Slider2Value);
                AdaptThresholding(app, hObject,handles);
                guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'CleanMarkersStdDev';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.slider1 = uislider(app.figure1);
            app.slider1.Limits = [0 1];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.slider1.Tag = 'slider1';
            app.slider2 = uislider(app.figure1);
            app.slider2.Limits = [0 1];
            app.slider2.MajorTicks = [];
            app.slider2.ValueChangedFcn = createCallbackFcn(app, @slider2_Callback, true);
            app.slider2.MinorTicks = [];
            app.slider2.Tag = 'slider2';
            app.Apply = uibutton(app.figure1, 'push');
            app.Apply.ButtonPushedFcn = createCallbackFcn(app, @Apply_Callback, true);
            app.Apply.Tag = 'Apply';
            app.Apply.Text = 'Apply';
            app.EdtMin1 = uieditfield(app.figure1, 'text');
            app.EdtMin1.Tag = 'EdtMin1';
            app.EdtMin1.Value = 'Edit Text';
            app.Min1 = uilabel(app.figure1);
            app.Min1.Tag = 'Min1';
            app.Min1.Text = 'Min';
            app.EdtCur1 = uieditfield(app.figure1, 'text');
            app.EdtCur1.Tag = 'EdtCur1';
            app.EdtCur1.Value = 'Edit Text';
            app.Cur1 = uilabel(app.figure1);
            app.Cur1.Tag = 'Cur1';
            app.Cur1.Text = 'Current';
            app.EdtMax1 = uieditfield(app.figure1, 'text');
            app.EdtMax1.Tag = 'EdtMax1';
            app.EdtMax1.Value = 'Edit Text';
            app.Max1 = uilabel(app.figure1);
            app.Max1.Tag = 'Max1';
            app.Max1.Text = 'Max';
            app.EdtMin2 = uieditfield(app.figure1, 'text');
            app.EdtMin2.Tag = 'EdtMin2';
            app.EdtMin2.Value = 'Edit Text';
            app.Min2 = uilabel(app.figure1);
            app.Min2.Tag = 'Min2';
            app.Min2.Text = 'Min';
            app.EdtCur2 = uieditfield(app.figure1, 'text');
            app.EdtCur2.Tag = 'EdtCur2';
            app.EdtCur2.Value = 'Edit Text';
            app.Cur2 = uilabel(app.figure1);
            app.Cur2.Tag = 'Cur2';
            app.Cur2.Text = 'Current';
            app.EdtMax2 = uieditfield(app.figure1, 'text');
            app.EdtMax2.Tag = 'EdtMax2';
            app.EdtMax2.Value = 'Edit Text';
            app.Max2 = uilabel(app.figure1);
            app.Max2.Tag = 'Max2';
            app.Max2.Text = 'Max';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CleanMarkersStdDev_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)CleanMarkersStdDev_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end