classdef DIC_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                 matlab.ui.Figure
        istressText                matlab.ui.control.Label
        Title                      matlab.ui.control.Label
        Preprocessing              matlab.ui.container.Panel
        GenerateFileListButton     matlab.ui.control.Button
        GenerateGridButton         matlab.ui.control.Button
        GenerateFilterListButton   matlab.ui.control.Button
        CheckImageQualityButton    matlab.ui.control.Button
        AverageImageStackButton    matlab.ui.control.Button
        Processing                 matlab.ui.container.Panel
        ProcessCorrelationsButton  matlab.ui.control.Button
        Postprocessing             matlab.ui.container.Panel
        ApplyCalibrationButton     matlab.ui.control.Button
        CreateCalibrationButton    matlab.ui.control.Button
        AnalyzeDataButton          matlab.ui.control.Button
        Logo1                      matlab.ui.control.UIAxes
        Logo2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        GridX = [];
        GridY = [];
        FileNameList = [];
        ValidX = [];
        ValidY = [];

        ImageLogo1=imread('istress.jpg');
        ImageLogo2=imread('cpcorr.jpg');
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DIC_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.MainFigure, 'onscreen');

            % axes(app.Logo1);
            imshow(app.ImageLogo1,'Parent',app.Logo1);
            % axes(app.Logo2);
            imshow(app.ImageLogo2,'Parent',app.Logo2);
        end

        % Button pushed function: GenerateFileListButton
        function GenerateFileListButton_Callback(app, event)
            [app.FileNameList] = GenerateFileList;
        end

        % Button pushed function: GenerateGridButton
        function GenerateGridButton_Callback(app, event)
            [app.GridX,app.GridY] = GenerateGrid;
        end

        % Button pushed function: GenerateFilterListButton
        function GenerateFilterListButton_Callback(app, event)
            GenerateFilterList;
        end

        % Button pushed function: CheckImageQualityButton
        function CheckImageQualityButton_Callback(app, event)
            CheckImageQuality;
        end

        % Button pushed function: AverageImageStackButton
        function AverageImageStackButton_Callback(app, event)
            AverageImageStack;
        end

        % Button pushed function: ProcessCorrelationsButton
        function ProcessCorrelationsButton_Callback(app, event)
            app.FileNameList
            [app.ValidX,app.ValidY] = ProcessCorrelations(0, app.GridX, app.GridY, app.FileNameList);
        end

        % Button pushed function: AnalyzeDataButton
        function AnalyzeDataButton_Callback(app, event)
            DisplacementAnalysis_App(app.ValidX, app.ValidY);
        end

        % Button pushed function: CreateCalibrationButton
        function CreateCalibrationButton_Callback(app, event)
            CreateCalibration;
        end

        % Button pushed function: ApplyCalibrationButton
        function ApplyCalibrationButton_Callback(app, event)
            ApplyCalibration;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            colormap(app.MainFigure, 'parula');
            app.MainFigure.Name = 'DIC';
            app.MainFigure.Tag = 'MainFigure';
            app.Logo2 = uiaxes(app.MainFigure);
            app.Logo2.Tag = 'Logo2';
            app.Logo1 = uiaxes(app.MainFigure);
            app.Logo1.Tag = 'Logo1';
            app.Postprocessing = uipanel(app.MainFigure);
            app.Postprocessing.Title = 'Postprocessing';
            app.Postprocessing.Tag = 'Postprocessing';
            app.AnalyzeDataButton = uibutton(app.Postprocessing, 'push');
            app.AnalyzeDataButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeDataButton_Callback, true);
            app.AnalyzeDataButton.Tag = 'BTNAnalyzeData';
            app.AnalyzeDataButton.Text = 'Analyze results';
            app.CreateCalibrationButton = uibutton(app.Postprocessing, 'push');
            app.CreateCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CreateCalibrationButton_Callback, true);
            app.CreateCalibrationButton.Tag = 'BTNCreateCalibration';
            app.CreateCalibrationButton.Text = 'Create calibration';
            app.ApplyCalibrationButton = uibutton(app.Postprocessing, 'push');
            app.ApplyCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyCalibrationButton_Callback, true);
            app.ApplyCalibrationButton.Tag = 'BTNApplyCalibration';
            app.ApplyCalibrationButton.Text = 'Apply calibration';
            app.Processing = uipanel(app.MainFigure);
            app.Processing.Title = 'Processing';
            app.Processing.Tag = 'Processing';
            app.ProcessCorrelationsButton = uibutton(app.Processing, 'push');
            app.ProcessCorrelationsButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessCorrelationsButton_Callback, true);
            app.ProcessCorrelationsButton.Tag = 'BTNProcessCorrelations';
            app.ProcessCorrelationsButton.Text = 'Process correlations';
            app.Preprocessing = uipanel(app.MainFigure);
            app.Preprocessing.Title = 'Preprocessing';
            app.Preprocessing.Tag = 'Preprocessing';
            app.AverageImageStackButton = uibutton(app.Preprocessing, 'push');
            app.AverageImageStackButton.ButtonPushedFcn = createCallbackFcn(app, @AverageImageStackButton_Callback, true);
            app.AverageImageStackButton.Tag = 'AverageImageStack';
            app.AverageImageStackButton.Text = 'Average image stack';
            app.CheckImageQualityButton = uibutton(app.Preprocessing, 'push');
            app.CheckImageQualityButton.ButtonPushedFcn = createCallbackFcn(app, @CheckImageQualityButton_Callback, true);
            app.CheckImageQualityButton.Tag = 'CheckImageQuality';
            app.CheckImageQualityButton.Text = 'Check image quality';
            app.GenerateFilterListButton = uibutton(app.Preprocessing, 'push');
            app.GenerateFilterListButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFilterListButton_Callback, true);
            app.GenerateFilterListButton.Tag = 'BTNGenerateFilterList';
            app.GenerateFilterListButton.Text = 'Generate filter list';
            app.GenerateGridButton = uibutton(app.Preprocessing, 'push');
            app.GenerateGridButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGridButton_Callback, true);
            app.GenerateGridButton.Tag = 'BTNGenerateGrid';
            app.GenerateGridButton.Text = 'Generate grid';
            app.GenerateFileListButton = uibutton(app.Preprocessing, 'push');
            app.GenerateFileListButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFileListButton_Callback, true);
            app.GenerateFileListButton.Tag = 'BTNGenerateFileList';
            app.GenerateFileListButton.Text = 'Generate file list';
            app.Title = uilabel(app.MainFigure);
            app.Title.Tag = 'Title';
            app.Title.Text = 'Digital Image Correlation';
            app.istressText = uilabel(app.MainFigure);
            app.istressText.Tag = 'text2';
            app.istressText.Text = 'www.istress.eu';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DIC_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)DIC_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MainFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end