classdef GenerateFilterList_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        ApplyFilterPushButton    matlab.ui.control.Button
        OpenImagePushButton      matlab.ui.control.Button
        RemoveFilterPushButton   matlab.ui.control.Button
        text4                    matlab.ui.control.Label
        CustomFiltersListBox     matlab.ui.control.ListBox
        text3                    matlab.ui.control.Label
        AvailableFiltersListBox  matlab.ui.control.ListBox
        WriteFilterPushButton    matlab.ui.control.Button
        AddFilterPushButton      matlab.ui.control.Button
        text2                    matlab.ui.control.Label
        text1                    matlab.ui.control.Label
        ImageAfterPartFig        matlab.ui.control.UIAxes
        ImageBeforePartFig       matlab.ui.control.UIAxes
        ImageAfterFig            matlab.ui.control.UIAxes
        ImageBeforeFig           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function handles = DrawRectangle(app, hObject, handles)
            
                XLim=get(gca,'XLim');
                YLim=get(gca,'YLim');
                ImageSize=size(handles.ImageBefore);
                ImageSize=round(ImageSize);
                handles.Rect=imrect(gca,[XLim(1) YLim(1) ImageSize(2) ImageSize(1)]);
                addNewPositionCallback(handles.Rect,@(Position) ImageBefore_Callback(app, Position,hObject));
                fcn=makeConstrainToRectFcn('imrect',XLim,YLim);
                setPositionConstraintFcn(handles.Rect,fcn);
        end
        
        function ImageBefore_Callback(app, Position, hObject)
            
                % Update images
                UpdateImages(app, Position,hObject);
        end
        
        function UpdateImages(app, Position, hObject)
            
                X1=round(Position(1));
                Y1=round(Position(2));
                X2=round(X1+Position(3))-1;
                Y2=round(Y1+Position(4))-1;
            
                % Check if positions are all positive
                if X1>0 && X2>0 && Y1>0 && Y2>0
                    handles=guidata(hObject);
            
                    % Before part
                    if isfield(handles,'ImageBefore')==1
                        handles.ImageBeforePart=handles.ImageBefore(Y1:Y2,X1:X2,:);
                        axes(handles.ImageBeforePartFig);
                        imshow(handles.ImageBeforePart);
                    end
            
                    % After part
                    if isfield(handles,'ImageAfter')==1
                        handles.ImageAfterPart=handles.ImageAfter(Y1:Y2,X1:X2,:);
                        axes(handles.ImageAfterPartFig);
                        imshow(handles.ImageAfterPart);
            
                        % Rect
                        axes(handles.ImageAfterFig);
                        imshow(handles.ImageAfter);
                        rectangle('Position',Position,'EdgeColor','b');
                    end
            
                    guidata(hObject,handles);
                end
        end
        
        function ImageAfter = WrapperAdapthisteq(app, ImageBefore, Options)
            
                % Wrap function call such that Options are expanted to list
                ImageAfter=adapthisteq(ImageBefore,Options{1},Options{2},Options{3},Options{4});
        end
        
        function ImageAfter = WrapperHisteq(app, ImageBefore)
            
                % Wrap function call such that no options are passed
                ImageAfter=histeq(ImageBefore);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GenerateFilterList_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
                handles.output = hObject;
            
                % Show empty images
                ImageInitial=ones(1,1);
                axes(handles.ImageBeforeFig);
                imshow(ImageInitial);
                axes(handles.ImageAfterFig);
                imshow(ImageInitial);
                axes(handles.ImageBeforePartFig);
                imshow(ImageInitial);
                axes(handles.ImageAfterPartFig);
                imshow(ImageInitial);
            
                % Create list of available filters
                handles.Filters=struct('Name','','ExecuteCommand','','WriteCommand','','Options',0,'OptionsString',0);
                handles.Filters(1).Name='Median';
                handles.Filters(1).ExecuteCommand='medfilt2';
                handles.Filters(1).WriteCommand=handles.Filters(1).ExecuteCommand;
                handles.Filters(1).Options=[5 5];
                handles.Filters(1).OptionsString='[5 5]';
            
                handles.Filters(2).Name='Gaussian';
                handles.Filters(2).ExecuteCommand='imfilter';
                handles.Filters(2).WriteCommand=handles.Filters(2).ExecuteCommand;
                handles.Filters(2).Options=fspecial('gaussian',[5 5],2);
                handles.Filters(2).OptionsString='fspecial("gaussian",[5 5],2)';
            
                handles.Filters(3).Name='Mean';
                handles.Filters(3).ExecuteCommand='imfilter';
                handles.Filters(3).WriteCommand=handles.Filters(3).ExecuteCommand;
                handles.Filters(3).Options=fspecial('average',5);
                handles.Filters(3).OptionsString='fspecial("average",5)';
            
                handles.Filters(4).Name='Sharpening';
                handles.Filters(4).ExecuteCommand='imfilter';
                handles.Filters(4).WriteCommand=handles.Filters(4).ExecuteCommand;
                handles.Filters(4).Options=fspecial('unsharp',0.05);
                handles.Filters(4).OptionsString='fspecial("unsharp",0.05)';
            
                handles.Filters(5).Name='Smoothing';
                handles.Filters(5).ExecuteCommand='imfilter';
                handles.Filters(5).WriteCommand=handles.Filters(5).ExecuteCommand;
                handles.Filters(5).Options=fspecial('gaussian',[5 5],5);
                handles.Filters(5).OptionsString='fspecial("gaussian",[5 5],5)';
            
            %     handles.Filters(6).Name='Contrast';
            %     handles.Filters(6).ExecuteCommand='WrapperAdapthisteq';
            %     handles.Filters(6).WriteCommand='adapthisteq';
            %     handles.Filters(6).Options={'clipLimit',0.02,'Distribution','rayleigh'};
            %     handles.Filters(6).OptionsString='"clipLimit",0.02,"Distribution","rayleigh"';
            
                handles.Filters(6).Name='Contrast';
                handles.Filters(6).ExecuteCommand='WrapperHisteq';
                handles.Filters(6).WriteCommand='histeq';
                handles.Filters(6).Options=0;
                handles.Filters(6).OptionsString='[]';
            
                % Init list boxes
                set(handles.AvailableFiltersListBox,'string',{handles.Filters.('Name')});
                set(handles.CustomFiltersListBox,'string',{});
            
                guidata(hObject,handles);
        end

        % Button pushed function: AddFilterPushButton
        function AddFilterPushButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                % Get selected filter from AvailableFiltersListBox
                SelectedFilter=get(handles.AvailableFiltersListBox,'Value');
            
                % Add selected filter to CustomFiltersListBox
                ExistingElements=get(handles.CustomFiltersListBox,'string');
                NewElement=handles.Filters(SelectedFilter).Name;
                if size(ExistingElements,1)==0
                    NewElements={NewElement}; % first element
                else
                    NewElements={ExistingElements{:,1},NewElement};
                end
                set(handles.CustomFiltersListBox,'string',NewElements);
            
                % Update filtered image and show in left figure
                if isfield(handles,'ImageAfter')==1
                    handles.ImageBefore=handles.ImageAfter;
                    axes(handles.ImageBeforeFig);
                    imshow(handles.ImageBefore);
            
                    % Draw rectangle for selecting image part
                    handles=DrawRectangle(app, hObject,handles);
                end
            
                guidata(hObject,handles);
            
                % Update
                UpdateImages(app, handles.Rect.getPosition,hObject);
        end

        % Button pushed function: ApplyFilterPushButton
        function ApplyFilterPushButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                % Get selected filter from AvailableFiltersListBox
                SelectedFilter=get(handles.AvailableFiltersListBox,'Value');
            
                % Apply filter (if image is not empty)
                if isfield(handles,'ImageBefore')==0
                    warning('Filter cannot be applied to empty image!');
                else
                    if handles.Filters(SelectedFilter).Options == 0 % no options
                        handles.ImageAfter=feval(handles.Filters(SelectedFilter).ExecuteCommand,handles.ImageBefore);
                    else
                        handles.ImageAfter=feval(handles.Filters(SelectedFilter).ExecuteCommand,handles.ImageBefore,handles.Filters(SelectedFilter).Options);
                    end
                end
            
                guidata(hObject,handles);
            
                % Update
                UpdateImages(app, handles.Rect.getPosition,hObject);
        end

        % Button pushed function: OpenImagePushButton
        function OpenImagePushButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                % Open image and show in left figure
                [ImageName,PathName]=uigetfile({'*.bmp;*.tif;*.tiff;*.jpg;*.jpeg;*.png','Image files (*.bmp,*.tif,*.tiff,*.jpg,*.jpeg;*.png)';'*.*','All Files (*.*)'},'Open image for filtering');
            
                if (ImageName)
                    cd(PathName);
            
                    % Show image (before)
                    handles.ImageBefore=imread(ImageName);
                    axes(handles.ImageBeforeFig);
                    imshow(handles.ImageBefore);
            
                    % Draw rectangle for selecting image part
                    handles=DrawRectangle(app, hObject,handles);
            
                    % Show image part (before)
                    handles.ImageBeforePart=handles.ImageBefore;
                    axes(handles.ImageBeforePartFig);
                    imshow(handles.ImageBeforePart);
                end
            
                guidata(hObject,handles);
        end

        % Button pushed function: RemoveFilterPushButton
        function RemoveFilterPushButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                % Get selected filter from CustomFiltersListBox
                SelectedFilter=get(handles.CustomFiltersListBox,'Value');
            
                % Remove selected filter from CustomFiltersListBox
                ExistingElements=get(handles.CustomFiltersListBox,'string');
                NumOfExistingElements=size(ExistingElements,1);
                if NumOfExistingElements>0 && SelectedFilter<=NumOfExistingElements
                    ExistingElements(SelectedFilter)=[];
                end
                set(handles.CustomFiltersListBox,'string',ExistingElements);
            
                if SelectedFilter>1
                    set(handles.CustomFiltersListBox,'Value',SelectedFilter-1);
                end
            
                guidata(hObject,handles);
        end

        % Button pushed function: WriteFilterPushButton
        function WriteFilterPushButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                % Get custom filter list from CustomFiltersListBox
                ExistingElements=get(handles.CustomFiltersListBox,'string');
                NumOfExistingElements=size(ExistingElements,1);
            
                % Available filters with details
                NumOfAvailableElements=size(handles.Filters,2);
            
                % Write custom filter config file
                FileID=fopen('CustomFilter.cfg','w');
            
                % Search in list of available filters for details of custom filters
                for ExistingElement=1:NumOfExistingElements
                    for AvailableElement=1:NumOfAvailableElements
                        if strcmp(ExistingElements{ExistingElement},handles.Filters(AvailableElement).Name)
                           OptionsString=strrep(handles.Filters(AvailableElement).OptionsString,'"',''''); % replace " by '
                           if strcmp(OptionsString,'[]') % no options
                               fprintf(FileID,'%s(Input);\n',handles.Filters(AvailableElement).WriteCommand); % Filter
                           else
                               fprintf(FileID,'%s(Input,%s);\n',handles.Filters(AvailableElement).WriteCommand,OptionsString); % Filter
                           end
                           break;
                        end
                    end
                end
            
                fclose(FileID);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'GenerateFilterList';
            app.figure1.Tag = 'figure1';
            app.ImageBeforeFig = uiaxes(app.figure1);
            app.ImageBeforeFig.Tag = 'ImageBeforeFig';
            app.ImageAfterFig = uiaxes(app.figure1);
            app.ImageAfterFig.Tag = 'ImageAfterFig';
            app.ImageBeforePartFig = uiaxes(app.figure1);
            app.ImageBeforePartFig.Tag = 'ImageBeforePartFig';
            app.ImageAfterPartFig = uiaxes(app.figure1);
            app.ImageAfterPartFig.Tag = 'ImageAfterPartFig';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Image before current filter applied';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Image after current filter applied';
            app.AddFilterPushButton = uibutton(app.figure1, 'push');
            app.AddFilterPushButton.ButtonPushedFcn = createCallbackFcn(app, @AddFilterPushButton_Callback, true);
            app.AddFilterPushButton.Tag = 'AddFilterPushButton';
            app.AddFilterPushButton.Text = 'Add selected filter';
            app.WriteFilterPushButton = uibutton(app.figure1, 'push');
            app.WriteFilterPushButton.ButtonPushedFcn = createCallbackFcn(app, @WriteFilterPushButton_Callback, true);
            app.WriteFilterPushButton.Tag = 'WriteFilterPushButton';
            app.WriteFilterPushButton.Text = 'Write custom filter list';
            app.AvailableFiltersListBox = uilistbox(app.figure1);
            app.AvailableFiltersListBox.Items = {'Available filters'};
            app.AvailableFiltersListBox.Tag = 'AvailableFiltersListBox';
            app.AvailableFiltersListBox.Value = 'Available filters';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Available filters';
            app.CustomFiltersListBox = uilistbox(app.figure1);
            app.CustomFiltersListBox.Items = {'Custom filters'};
            app.CustomFiltersListBox.Tag = 'CustomFiltersListBox';
            app.CustomFiltersListBox.Value = 'Custom filters';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Custom filters';
            app.RemoveFilterPushButton = uibutton(app.figure1, 'push');
            app.RemoveFilterPushButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFilterPushButton_Callback, true);
            app.RemoveFilterPushButton.Tag = 'RemoveFilterPushButton';
            app.RemoveFilterPushButton.Text = 'Remove selected filter';
            app.OpenImagePushButton = uibutton(app.figure1, 'push');
            app.OpenImagePushButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImagePushButton_Callback, true);
            app.OpenImagePushButton.Tag = 'OpenImagePushButton';
            app.OpenImagePushButton.Text = 'Open image';
            app.ApplyFilterPushButton = uibutton(app.figure1, 'push');
            app.ApplyFilterPushButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterPushButton_Callback, true);
            app.ApplyFilterPushButton.Tag = 'ApplyFilterPushButton';
            app.ApplyFilterPushButton.Text = 'Apply selected filter';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GenerateFilterList_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GenerateFilterList_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end