classdef DPParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                     matlab.ui.Figure
        CurrentseriesLabel             matlab.ui.control.Label
        xStrainEditField               matlab.ui.control.EditField
        BrowseXStrainButton            matlab.ui.control.Button
        yStrainEditField               matlab.ui.control.EditField
        CurrentpillarLabel             matlab.ui.control.Label
        BrowseYStrainButton            matlab.ui.control.Button
        SelectedseriesLabel            matlab.ui.control.Label
        FourtyFiveStrainEditField      matlab.ui.control.EditField
        Browse45StrainButton           matlab.ui.control.Button
        DPTypeButtonGroup              matlab.ui.container.ButtonGroup
        EquibiaxialButton              matlab.ui.control.RadioButton
        NonEquibiaxialButton           matlab.ui.control.RadioButton
        FullFieldButton                matlab.ui.control.RadioButton
        PolynomialdegreeLabel          matlab.ui.control.Label
        PointsspanLabel                matlab.ui.control.Label
        PillarreductionfactorDropDownLabel  matlab.ui.control.Label
        xAxisDataButtonGroup           matlab.ui.container.ButtonGroup
        DepthButton                    matlab.ui.control.RadioButton
        NormalizedDepthButton          matlab.ui.control.RadioButton
        xBoundariesLabel               matlab.ui.control.Label
        xLowBoundEditField             matlab.ui.control.NumericEditField
        xHighBoundEditField            matlab.ui.control.NumericEditField
        yBoundariesLabel               matlab.ui.control.Label
        yLowBoundLabel                 matlab.ui.control.Label
        yLowBoundEditField             matlab.ui.control.NumericEditField
        yHighBoundLabel                matlab.ui.control.Label
        yHighBoundEditField            matlab.ui.control.NumericEditField
        GeometricalPropertiesLabel     matlab.ui.control.Label
        TheoreticalstepsizemLabel      matlab.ui.control.Label
        PillarDiameterEditField        matlab.ui.control.NumericEditField
        PillardiametermLabel           matlab.ui.control.Label
        TheoreticalStepSizeEditField   matlab.ui.control.NumericEditField
        DICparametersLabel             matlab.ui.control.Label
        UpperlimitLabel                matlab.ui.control.Label
        PointsspanLabel_2              matlab.ui.control.Label
        PillarReductionFactorDropDown  matlab.ui.control.DropDown
        PointsSpanSmoothingEditField   matlab.ui.control.Spinner
        DataSmoothingLabel             matlab.ui.control.Label
        PoissonratioEditFieldLabel     matlab.ui.control.Label
        PolynomialDegreeSmoothingEditField  matlab.ui.control.Spinner
        MaterialPropertiesLabel        matlab.ui.control.Label
        ElasticModulusEditField        matlab.ui.control.NumericEditField
        ElasticmodulusGPaEditFieldLabel  matlab.ui.control.Label
        PoissonRatioEditField          matlab.ui.control.NumericEditField
        OKButton                       matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Data selection
        xStrainFile
        xStrainPath
        yStrainFile
        yStrainPath
        FourtyFiveStrainFile
        FourtyFiveStrainPath

        % Strain direction
        strainDirection

        % Plotting options
        xLowBound
        xHighBound
        yLowBound
        yHighBound
        
        % Geometrical properties
        pillarDiameter
        theoStepSize
        
        % DIC parameters
        pillarRedFactor
        
        % Smoothing parameters
        pointsSpan
        polyDegree

        % Material properties
        elasticModulus
        poissonRatio

        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, DPType, xStrainFile, xStrainPath, yStrainFile, yStrainPath, FourtyFiveStrainFile, FourtyFiveStrainPath, xData, xLowBound, xHighBound, yLowBound, yHighBound, pillarDiameter, theoStepSize, pillarRedFactor, pointsSpan, polyDegree, elasticModulus, poissonRatio)
            % Paths
            app.xStrainEditField.Value = xStrainFile;
            app.xStrainPath = xStrainPath;
            app.yStrainEditField.Value = yStrainFile;
            app.yStrainPath = yStrainPath;
            app.FourtyFiveStrainEditField.Value = FourtyFiveStrainFile;
            app.FourtyFiveStrainPath = FourtyFiveStrainPath;
            
            % Depth profiling type
            if strcmp(DPType,"Equibiaxial")
                app.EquibiaxialButton.Value = true;
            elseif strcmp(DPType,"Non-equibiaxial")
                app.NonEquibiaxialButton.Value = true;
            else
                app.FullFieldButton.Value = true;
            end
            
            % x axis data
            if strcmp(xData, 'Depth')
                app.DepthButton.Value = true;
            else
                app.NormalizedDepthButton.Value = true;
            end

            % Plotting options
            app.xLowBoundEditField.Value = xLowBound;
            app.xHighBoundEditField.Value = xHighBound;
            app.yLowBoundEditField.Value = yLowBound;
            app.yHighBoundEditField.Value = yHighBound;

            % Geometrical properties
            app.PillarDiameterEditField.Value = pillarDiameter;
            app.TheoreticalStepSizeEditField.Value = theoStepSize;

            % DIC parameters
            app.PillarReductionFactorDropDown.Value = num2str(pillarRedFactor);

            % Smoothing parameters
            app.PointsSpanSmoothingEditField.Value = pointsSpan;
            app.PolynomialDegreeSmoothingEditField.Value = polyDegree;

            % Material properties
            app.ElasticModulusEditField.Value = elasticModulus;
            app.PoissonRatioEditField.Value = poissonRatio;

            app.mainapp = mainapp;

            DPTypeChangeCallback(app)
        end

        % Selection changed function: DPTypeButtonGroup
        function DPTypeChangeCallback(app, event)
            if app.EquibiaxialButton.Value == true
                app.BrowseYStrainButton.Enable = "off";
                app.Browse45StrainButton.Enable = "off";
            elseif app.NonEquibiaxialButton.Value == true
                app.BrowseYStrainButton.Enable = "on";
                app.Browse45StrainButton.Enable = "off";
            else
                app.BrowseYStrainButton.Enable = "on";
                app.Browse45StrainButton.Enable = "on";
            end
        end

        % Button pushed function: BrowseXStrainButton
        function BrowseXStrainCallback(app, event)
            [app.xStrainFile, app.xStrainPath] = uigetfile('*.dat','Select the strain file for x.');
            if app.xStrainFile == 0 | isempty(app.xStrainFile)
                return
            end
            app.xStrainEditField.Value = app.xStrainFile;
        end

        % Button pushed function: BrowseYStrainButton
        function BrowseYStrainCallback(app, event)
            [app.yStrainFile, app.yStrainPath] = uigetfile('*.dat','Select the strain file for y.');
            if app.yStrainFile == 0 | isempty(app.yStrainFile)
                return
            end
            app.yStrainEditField.Value = app.yStrainFile;
        end

        % Button pushed function: Browse45StrainButton
        function Browse45StrainCallback(app, event)
            [app.FourtyFiveStrainFile, app.FourtyFiveStrainPath] = uigetfile('*.dat','Select the strain file for 45°.');
            if app.FourtyFiveStrainFile == 0 | isempty(app.FourtyFiveStrainFile)
                return
            end
            app.FourtyFiveStrainEditField.Value = app.FourtyFiveStrainFile;
        end

        % Button pushed function: OKButton
        function OKCallback(app, event)
            % Depth profiling type
            if app.EquibiaxialButton.Value == true
                app.mainapp.DPType = "Equibiaxial";
            elseif app.NonEquibiaxialButton.Value == true
                app.mainapp.DPType = "Non-equibiaxial";
            else
                app.mainapp.DPType = "Full field";
            end

            % Paths
            app.mainapp.xStrainFile = app.xStrainEditField.Value;
            app.mainapp.xStrainPath = app.xStrainPath;
            app.mainapp.yStrainFile = app.yStrainEditField.Value;
            app.mainapp.yStrainPath = app.yStrainPath;
            app.mainapp.FourtyFiveStrainFile = app.FourtyFiveStrainEditField.Value;
            app.mainapp.FourtyFiveStrainPath = app.FourtyFiveStrainPath;

            % Milling parameters
            app.mainapp.pillarDiameter = app.PillarDiameterEditField.Value;
            app.mainapp.theoStepSize = app.TheoreticalStepSizeEditField.Value;

            % Material properties
            app.mainapp.elasticModulus = app.ElasticModulusEditField.Value;
            app.mainapp.poissonRatio = app.PoissonRatioEditField.Value;

            % x axis data
            if app.DepthButton.Value == true
                app.mainapp.xData = "Depth";
            else
                app.mainapp.xData = "Normalized depth";
            end
            
            % Plotting options
            app.mainapp.xLowBound = app.xLowBoundEditField.Value;
            app.mainapp.xHighBound = app.xHighBoundEditField.Value;
            app.mainapp.yLowBound = app.yLowBoundEditField.Value;
            app.mainapp.yHighBound = app.yHighBoundEditField.Value;

            % DIC parameters
            app.mainapp.pillarRedFactor = str2double(app.PillarReductionFactorDropDown.Value);

            % Smoothing parameters
            app.mainapp.pointsSpan = app.PointsSpanSmoothingEditField.Value;
            app.mainapp.polyDegree = app.PolynomialDegreeSmoothingEditField.Value;

            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelCallback(app, event)
            delete(app)
        end

        % Selection changed function: xAxisDataButtonGroup
        function xAxisDataChangedCallback(app, event)
            if app.DepthButton.Value == true
                app.xBoundariesLabel.Text = "Boundaries for x [µm]";
                app.xLowBoundEditField.Value = 0;
                app.xHighBoundEditField.Value = app.PillarDiameterEditField.Value*.2;
            else
                app.xBoundariesLabel.Text = "Boundaries for x [no unit]";
                app.xLowBoundEditField.Value = 0.05;
                app.xHighBoundEditField.Value = 0.2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Depth profiling parameters';
            app.MainWindow.Icon = fullfile(pathToMLAPP, 'DP_app_resources', 'icon-DP_app.png');
            app.CancelButton = uibutton(app.MainWindow, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCallback, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.MainWindow, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKCallback, true);
            app.OKButton.Text = 'OK';
            app.PoissonRatioEditField = uieditfield(app.MainWindow, 'numeric');
            app.PoissonRatioEditField.Limits = [0 Inf];
            app.PoissonRatioEditField.AllowEmpty = 'on';
            app.PoissonRatioEditField.Value = 0.36;
            app.ElasticmodulusGPaEditFieldLabel = uilabel(app.MainWindow);
            app.ElasticmodulusGPaEditFieldLabel.Text = 'Elastic modulus [GPa]';
            app.ElasticModulusEditField = uieditfield(app.MainWindow, 'numeric');
            app.ElasticModulusEditField.AllowEmpty = 'on';
            app.ElasticModulusEditField.Value = 120;
            app.MaterialPropertiesLabel = uilabel(app.MainWindow);
            app.MaterialPropertiesLabel.Text = 'Material properties';
            app.PolynomialDegreeSmoothingEditField = uispinner(app.MainWindow);
            app.PolynomialDegreeSmoothingEditField.AllowEmpty = 'on';
            app.PolynomialDegreeSmoothingEditField.Value = 3;
            app.PoissonratioEditFieldLabel = uilabel(app.MainWindow);
            app.PoissonratioEditFieldLabel.Text = 'Poisson ratio';
            app.DataSmoothingLabel = uilabel(app.MainWindow);
            app.DataSmoothingLabel.Text = 'Data smoothing parameters';
            app.PointsSpanSmoothingEditField = uispinner(app.MainWindow);
            app.PointsSpanSmoothingEditField.AllowEmpty = 'on';
            app.PointsSpanSmoothingEditField.Value = 6;
            app.PillarReductionFactorDropDown = uidropdown(app.MainWindow);
            app.PillarReductionFactorDropDown.Items = {'1', '0.8', '0.6'};
            app.PillarReductionFactorDropDown.Value = '0.8';
            app.PointsspanLabel_2 = uilabel(app.MainWindow);
            app.PointsspanLabel_2.Text = 'Low boundary';
            app.UpperlimitLabel = uilabel(app.MainWindow);
            app.UpperlimitLabel.Text = 'High boundary';
            app.DICparametersLabel = uilabel(app.MainWindow);
            app.DICparametersLabel.Text = 'DIC parameters';
            app.TheoreticalStepSizeEditField = uieditfield(app.MainWindow, 'numeric');
            app.TheoreticalStepSizeEditField.Limits = [0 Inf];
            app.TheoreticalStepSizeEditField.AllowEmpty = 'on';
            app.TheoreticalStepSizeEditField.Value = 0.25;
            app.PillardiametermLabel = uilabel(app.MainWindow);
            app.PillardiametermLabel.Text = 'Pillar diameter [µm]';
            app.PillarDiameterEditField = uieditfield(app.MainWindow, 'numeric');
            app.PillarDiameterEditField.Limits = [0 Inf];
            app.PillarDiameterEditField.AllowEmpty = 'on';
            app.PillarDiameterEditField.Value = 10;
            app.TheoreticalstepsizemLabel = uilabel(app.MainWindow);
            app.TheoreticalstepsizemLabel.Text = 'Theoretical step size [µm]';
            app.GeometricalPropertiesLabel = uilabel(app.MainWindow);
            app.GeometricalPropertiesLabel.Text = 'Milling parameters';
            app.yHighBoundEditField = uieditfield(app.MainWindow, 'numeric');
            app.yHighBoundEditField.AllowEmpty = 'on';
            app.yHighBoundEditField.Value = [];
            app.yHighBoundLabel = uilabel(app.MainWindow);
            app.yHighBoundLabel.Text = 'High boundary';
            app.yLowBoundEditField = uieditfield(app.MainWindow, 'numeric');
            app.yLowBoundEditField.AllowEmpty = 'on';
            app.yLowBoundEditField.Value = [];
            app.yLowBoundLabel = uilabel(app.MainWindow);
            app.yLowBoundLabel.Text = 'Low boundary';
            app.yBoundariesLabel = uilabel(app.MainWindow);
            app.yBoundariesLabel.Text = 'Boundaries for y (= stress) [MPa]';
            app.xHighBoundEditField = uieditfield(app.MainWindow, 'numeric');
            app.xHighBoundEditField.AllowEmpty = 'on';
            app.xHighBoundEditField.Value = 0.2;
            app.xLowBoundEditField = uieditfield(app.MainWindow, 'numeric');
            app.xLowBoundEditField.AllowEmpty = 'on';
            app.xBoundariesLabel = uilabel(app.MainWindow);
            app.xBoundariesLabel.Text = 'Boundaries for x [no unit]';
            app.xAxisDataButtonGroup = uibuttongroup(app.MainWindow);
            app.xAxisDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @xAxisDataChangedCallback, true);
            app.xAxisDataButtonGroup.Tooltip = {'I will change it soon I promise'};
            app.xAxisDataButtonGroup.Title = 'x axis data';
            app.NormalizedDepthButton = uiradiobutton(app.xAxisDataButtonGroup);
            app.NormalizedDepthButton.Tooltip = {'depth/(pillar diameter)'};
            app.NormalizedDepthButton.Text = 'Normalized depth';
            app.NormalizedDepthButton.Value = true;
            app.DepthButton = uiradiobutton(app.xAxisDataButtonGroup);
            app.DepthButton.Text = 'Depth [µm]';
            app.PillarreductionfactorDropDownLabel = uilabel(app.MainWindow);
            app.PillarreductionfactorDropDownLabel.Text = 'Pillar reduction factor';
            app.PointsspanLabel = uilabel(app.MainWindow);
            app.PointsspanLabel.Text = 'Points span';
            app.PolynomialdegreeLabel = uilabel(app.MainWindow);
            app.PolynomialdegreeLabel.Text = 'Polynomial degree';
            app.DPTypeButtonGroup = uibuttongroup(app.MainWindow);
            app.DPTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DPTypeChangeCallback, true);
            app.DPTypeButtonGroup.Tooltip = {'I will change it soon I promise'};
            app.DPTypeButtonGroup.Title = 'Depth profiling type';
            app.FullFieldButton = uiradiobutton(app.DPTypeButtonGroup);
            app.FullFieldButton.Tooltip = {'Requires strains in 3 directions'};
            app.FullFieldButton.Text = 'Full field';
            app.NonEquibiaxialButton = uiradiobutton(app.DPTypeButtonGroup);
            app.NonEquibiaxialButton.Tooltip = {'Requires strains in 2 directions'};
            app.NonEquibiaxialButton.Text = 'Non-equibiaxial';
            app.EquibiaxialButton = uiradiobutton(app.DPTypeButtonGroup);
            app.EquibiaxialButton.Tooltip = {'Requires strain in 1 direction'};
            app.EquibiaxialButton.Text = 'Equibiaxial';
            app.EquibiaxialButton.Value = true;
            app.Browse45StrainButton = uibutton(app.MainWindow, 'push');
            app.Browse45StrainButton.ButtonPushedFcn = createCallbackFcn(app, @Browse45StrainCallback, true);
            app.Browse45StrainButton.Text = 'Browse...';
            app.FourtyFiveStrainEditField = uieditfield(app.MainWindow, 'text');
            app.FourtyFiveStrainEditField.Editable = 'off';
            app.SelectedseriesLabel = uilabel(app.MainWindow);
            app.SelectedseriesLabel.Text = 'Strain in x direction';
            app.BrowseYStrainButton = uibutton(app.MainWindow, 'push');
            app.BrowseYStrainButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseYStrainCallback, true);
            app.BrowseYStrainButton.Text = 'Browse...';
            app.CurrentpillarLabel = uilabel(app.MainWindow);
            app.CurrentpillarLabel.Text = 'Strain in y direction';
            app.yStrainEditField = uieditfield(app.MainWindow, 'text');
            app.yStrainEditField.Editable = 'off';
            app.BrowseXStrainButton = uibutton(app.MainWindow, 'push');
            app.BrowseXStrainButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseXStrainCallback, true);
            app.BrowseXStrainButton.Text = 'Browse...';
            app.xStrainEditField = uieditfield(app.MainWindow, 'text');
            app.xStrainEditField.Editable = 'off';
            app.CurrentseriesLabel = uilabel(app.MainWindow);
            app.CurrentseriesLabel.Text = 'Strain in 45° direction';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DPParameters(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainWindow)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MainWindow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end