classdef DP_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SavingButton                    matlab.ui.control.StateButton
        Title                           matlab.ui.control.Label
        CloseAllButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        DataSelectionTab                matlab.ui.container.Tab
        DirectionButtonGroup            matlab.ui.container.ButtonGroup
        xButton                         matlab.ui.control.RadioButton
        yButton                         matlab.ui.control.RadioButton
        WorkfolderLabel                 matlab.ui.control.Label
        CurrentseriesLabel              matlab.ui.control.Label
        CurrentSampleEditField          matlab.ui.control.EditField
        BrowseCurrentSampleButton       matlab.ui.control.Button
        CurrentPillarEditField          matlab.ui.control.EditField
        BrowseCurrentPillarButton       matlab.ui.control.Button
        CurrentSeriesEditField          matlab.ui.control.EditField
        BrowseCurrentSeriesButton       matlab.ui.control.Button
        CurrentpillarLabel              matlab.ui.control.Label
        StackpretreatmentButton         matlab.ui.control.Button
        NbImagesStep                    matlab.ui.control.NumericEditField
        NumberofimagespermillingstepEditFieldLabel  matlab.ui.control.Label
        WorkFolder                      matlab.ui.control.EditField
        SelectedseriesLabel             matlab.ui.control.Label
        DICTab                          matlab.ui.container.Tab
        LastimagefullnameLabel          matlab.ui.control.Label
        GenerateFileListButton          matlab.ui.control.Button
        FileNamesRootEditField          matlab.ui.control.EditField
        FirstimagefullnameEditFieldLabel  matlab.ui.control.Label
        FirstImageNumberEditField       matlab.ui.control.NumericEditField
        NumberOfImagesEditField         matlab.ui.control.NumericEditField
        NumberOfDigitsEditField         matlab.ui.control.NumericEditField
        ImagesFormatDropDown            matlab.ui.control.DropDown
        FirstImageFullNameEditField     matlab.ui.control.EditField
        ImagesformatDropDownLabel       matlab.ui.control.Label
        LastImageFullNameEditField      matlab.ui.control.EditField
        NumberofdigitsEditFieldLabel    matlab.ui.control.Label
        ProcessCorrelationsButton       matlab.ui.control.Button
        NumberofimagesEditFieldLabel    matlab.ui.control.Label
        CorrelationSize                 matlab.ui.control.NumericEditField
        FirstimagenumberEditFieldLabel  matlab.ui.control.Label
        FilenamesrootEditFieldLabel     matlab.ui.control.Label
        DisplacementtypeDropDownLabel   matlab.ui.control.Label
        ReferenceimageDropDownLabel     matlab.ui.control.Label
        ReductionFactor                 matlab.ui.control.NumericEditField
        ProcessingMode                  matlab.ui.control.DropDown
        ProcessingmodeDropDownLabel     matlab.ui.control.Label
        ReductionfactorEditFieldLabel   matlab.ui.control.Label
        CorrelationsizeEditFieldLabel   matlab.ui.control.Label
        ReferenceImage                  matlab.ui.control.DropDown
        DisplacementType                matlab.ui.control.DropDown
        DisplacementAnalysisButton      matlab.ui.control.Button
        DepthProfilingTab               matlab.ui.container.Tab
        HigherlimitLabel                matlab.ui.control.Label
        LowerlimitLabel                 matlab.ui.control.Label
        PlotOptions                     matlab.ui.container.ButtonGroup
        SuccessivePlots                 matlab.ui.control.RadioButton
        CombinedPlots                   matlab.ui.control.RadioButton
        UncertaintyNormalizedDepthCalculation  matlab.ui.control.Button
        DP1SeriesButton                 matlab.ui.control.Button
        Averaging1PillarButton          matlab.ui.control.Button
        DisplayIndividualPlotsCheckbox  matlab.ui.control.CheckBox
        StressLimitsLabel               matlab.ui.control.Label
        StressLowerLimit                matlab.ui.control.NumericEditField
        StressHigherLimit               matlab.ui.control.NumericEditField
        StringSampleButton              matlab.ui.control.Button
        GeometricalPropertiesLabel      matlab.ui.control.Label
        PillarDiameter                  matlab.ui.control.NumericEditField
        TheoreticalStepSize             matlab.ui.control.NumericEditField
        DICparametersLabel              matlab.ui.control.Label
        PillarReductionFactor           matlab.ui.control.NumericEditField
        LimitsDPLabel                   matlab.ui.control.Label
        LowerLimitDP                    matlab.ui.control.NumericEditField
        UpperLimitDP                    matlab.ui.control.NumericEditField
        DataSmoothingLabel              matlab.ui.control.Label
        PointsSpanSmoothing             matlab.ui.control.NumericEditField
        PolynomialDegreeSmoothing       matlab.ui.control.NumericEditField
        MaterialPropertiesLabel         matlab.ui.control.Label
        ElasticModulus                  matlab.ui.control.NumericEditField
        PoissonratioEditFieldLabel      matlab.ui.control.Label
        ElasticmodulusGPaEditFieldLabel  matlab.ui.control.Label
        PolynomialdegreeEditFieldLabel  matlab.ui.control.Label
        PointsspanLabel                 matlab.ui.control.Label
        UpperlimitLabel                 matlab.ui.control.Label
        PointsspanLabel_2               matlab.ui.control.Label
        PillarreductionfactorEditFieldLabel  matlab.ui.control.Label
        TheoreticalstepsizeEditFieldLabel  matlab.ui.control.Label
        PillardiameterEditFieldLabel    matlab.ui.control.Label
        PoissonRatio                    matlab.ui.control.NumericEditField
        SamplesComparisonTab            matlab.ui.container.Tab
        ComparisonFilenameEditField     matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        Namecurve2EditField             matlab.ui.control.EditField
        Namecurve2EditFieldLabel        matlab.ui.control.Label
        Namecurve1EditField             matlab.ui.control.EditField
        Namecurve1EditFieldLabel        matlab.ui.control.Label
        BrowseButtonSample2_2           matlab.ui.control.Button
        SavingdirectoryEditField        matlab.ui.control.EditField
        SavingdirectoryEditFieldLabel   matlab.ui.control.Label
        PillarDiameterComparison        matlab.ui.control.NumericEditField
        PillardiameterEditFieldLabel_2  matlab.ui.control.Label
        PlotOptions2                    matlab.ui.container.ButtonGroup
        SuccessivePlots2                matlab.ui.control.RadioButton
        CombinedPlots2                  matlab.ui.control.RadioButton
        PlotOptions3                    matlab.ui.container.ButtonGroup
        FullComparison                  matlab.ui.control.RadioButton
        NotFullComparison               matlab.ui.control.RadioButton
        Sample1EditField                matlab.ui.control.EditField
        BrowseButtonSample1             matlab.ui.control.Button
        Sample2EditField                matlab.ui.control.EditField
        Sample2EditFieldLabel           matlab.ui.control.Label
        BrowseButtonSample2             matlab.ui.control.Button
        Sample1EditFieldLabel           matlab.ui.control.Label
        CompareButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        currentSamplePath = '';
        currentPillarPath = '';
        currentSeriesPath = '';
        sample1Path = '';
        sample2Path = '';
        savingPath = '';

        % DIC
        filenameList = char(strings(1,1));
        
        validX = [];
        validY = [];

        stdX = [];
        stdY = [];

        corrCoef = [];

        gridX = [];
        gridY = [];

        % Paths management
        appPath = '';
    end
    
    methods (Access = private)
        
        function loadFilesSeries(app)
            try
                app.validX = importdata(fullfile(app.WorkFolder.Value,'validx.dat'),'\t');
                app.validY = importdata(fullfile(app.WorkFolder.Value,'validy.dat'),'\t');
                app.stdX = importdata(fullfile(app.WorkFolder.Value,'stdx.dat'),'\t');
                app.stdY = importdata(fullfile(app.WorkFolder.Value,'stdy.dat'),'\t');
                app.corrCoef = importdata(fullfile(app.WorkFolder.Value,'corrcoef.dat'),'\t');
            catch
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AppStartupFcn(app)
            % add the DIC app path to use the same function
            [app.appPath,~] = fileparts(mfilename("fullpath"));
            addpath(fullfile(app.appPath,'..','DIC_app_designer-kento','code'))
        end

        % Value changed function: SavingButton
        function SavingButtonCallback(app, event)
            if strcmp(app.SavingButton.Text, 'Saving on')
                app.SavingButton.Text = 'Saving off';
            else
                app.SavingButton.Text = 'Saving on';
            end
        end

        % Button pushed function: CloseAllButton
        function CloseAllCallback(app, event)
            close all
        end

        % Button pushed function: BrowseCurrentSampleButton
        function BrowseCurrentSampleCallback(app, event)
            app.currentSamplePath = uigetdir('', "Select the sample directory.");

            if app.currentSamplePath == 0 | isempty(app.currentSamplePath)
                disp('No directory selected. Stopping the execution.')
                return
            end
            cd(app.currentSamplePath)
            [~, app.CurrentSampleEditField.Value] = fileparts(app.currentSamplePath);
            app.CurrentPillarEditField.Value = '';
            app.CurrentSeriesEditField.Value = '';
            figure(app.UIFigure)
        end

        % Button pushed function: BrowseCurrentPillarButton
        function BrowseCurrentPillarCallback(app, event)
            app.currentPillarPath = uigetdir('', "Select the pillar directory.");

            if app.currentPillarPath == 0 | isempty(app.currentPillarPath)
                disp('No directory selected. Stopping the execution.')
                return
            end
            cd(app.currentPillarPath)
            [app.currentSamplePath, app.CurrentPillarEditField.Value] = fileparts(app.currentPillarPath);
            [~, app.CurrentSampleEditField.Value] = fileparts(app.currentSamplePath);
            app.CurrentSeriesEditField.Value = '';
            app.PillarDiameter.Value = str2double(app.CurrentPillarEditField.Value(1:2));
            if app.PillarDiameter.Value == 5
                app.TheoreticalStepSize.Value = 0.125;
            else
                app.TheoreticalStepSize.Value = app.PillarDiameter.Value/80;
            end
            figure(app.UIFigure)
        end

        % Value changed function: CurrentSeriesEditField
        function CurrentSeriesManualChangeCallback(app, event)
            newSeriesPath = fullfile(app.currentPillarPath,event.Value);
            if isfolder(newSeriesPath)
                app.currentSeriesPath = newSeriesPath;
                cd(newSeriesPath)
            else
                msgbox('Series directory not found.')
            end
            if app.xButton.Value
                app.WorkFolder.Value = fullfile(app.currentSeriesPath,'averaged','x');
            else
                app.WorkFolder.Value = fullfile(app.currentSeriesPath,'averaged','y');
            end
            loadFilesSeries(app)
        end

        % Button pushed function: BrowseCurrentSeriesButton
        function BrowseCurrentSeriesCallback(app, event)
            app.currentSeriesPath = uigetdir('', "Select the series directory.");

            if app.currentSeriesPath == 0 | isempty(app.currentSeriesPath)
                disp('No directory selected. Stopping the execution.')
                return
            end
            cd(app.currentSeriesPath)

            [app.currentPillarPath, app.CurrentSeriesEditField.Value] = fileparts(app.currentSeriesPath);
            [app.currentSamplePath, app.CurrentPillarEditField.Value] = fileparts(app.currentPillarPath);
            [~, app.CurrentSampleEditField.Value] = fileparts(app.currentSamplePath);
            try
                app.PillarDiameter.Value = str2double(app.CurrentPillarEditField.Value(1:2));
                if app.PillarDiameter.Value == 5
                    app.TheoreticalStepSize.Value = 0.125;
                else
                    app.TheoreticalStepSize.Value = app.PillarDiameter.Value/20;
                end
            catch
                app.CurrentSampleEditField.Value = '';
                app.CurrentPillarEditField.Value = '';
                msgbox('No pillar diameter detected. Change the pillar diameter and the theoretical step size in the parameters panel.')
            end
            if app.xButton.Value
                app.WorkFolder.Value = fullfile(app.currentSeriesPath,'averaged','x');
            else
                app.WorkFolder.Value = fullfile(app.currentSeriesPath,'averaged','y');
            end
            loadFilesSeries(app)
            figure(app.UIFigure)
        end

        % Button pushed function: StackpretreatmentButton
        function StackPretreatmentCallback(app, event)
            if app.xButton.Value
                try
                    cd(app.currentSeriesPath)
                    pretreatment_FIB_images_x(app.appPath,app.currentSeriesPath,app.SavingButton.Value, app.NbImagesStep.Value);
                catch
                    return
                end
            else
                try
                    cd(app.currentSeriesPath)
                    pretreatment_FIB_images_y(app.appPath,app.currentSeriesPath,app.SavingButton.Value, app.NbImagesStep.Value);
                catch
                    return
                end
            end
        end

        % Button pushed function: UncertaintyNormalizedDepthCalculation
        function UncertaintyNormalizedDepthCalculationCallback(app, event)
            uncertaintyNormalizedDepthCSV = fullfile(app.WorkFolder.Value,'uncertainty-normalized_depth.csv');
            if isfile(uncertaintyNormalizedDepthCSV)
                calculation_uncertainty_normalized_depth(app.SavingButton.Value,app.WorkFolder.Value,app.PillarDiameter.Value, ...
                    app.TheoreticalStepSize.Value)
            else
                msgbox('Uncertainty file not found.')
                return
            end
        end

        % Button pushed function: GenerateFileListButton
        function GenerateFilelistCallback(app, event)
            app.filenameList = strings(app.NumberOfImagesEditField.Value,1);
            formatString = sprintf('%%0%dd',app.NumberOfDigitsEditField.Value);
            for imageNum = 1:app.NumberOfImagesEditField.Value
                imageNumFullDigits = sprintf(formatString,imageNum-1);
                imageName = sprintf('%s%s.%s',app.FileNamesRootEditField.Value, ...
                    imageNumFullDigits,app.ImagesFormatDropDown.Value);
                app.filenameList(imageNum,1) = imageName;
            end
            app.filenameList = char(app.filenameList);
            if app.SavingButton.Value == true
                FileNameList = app.filenameList;
                save(fullfile(app.WorkFolder.Value,'filenamelist.mat'),'FileNameList')
                msgbox('Filename list created and saved.')
            else
                msgbox('Filename list not saved.')
            end
        end

        % Button pushed function: ProcessCorrelationsButton
        function ProcessCorrelationsCallback(app, event)
            cd(app.WorkFolder.Value)
            try
                filenameListFile = load(fullfile(cd,'filenamelist.mat'));
                app.filenameList = filenameListFile.FileNameList;
            catch
                msgbox('Filename list not found.')
            end
            [app.validX,app.validY,app.stdX,app.stdY,app.corrCoef] = ProcessCorrelations(0, app.gridX, app.gridY, app.filenameList, app.validX, ...
                app.validY, app.CorrelationSize.Value, app.ProcessingMode.Value, app.ReferenceImage.Value, app.DisplacementType.Value, ...
                app.ReductionFactor.Value);
        end

        % Button pushed function: DisplacementAnalysisButton
        function DisplacementAnalysisCallback(app, event)
            if strcmp(app.currentSeriesPath,'')
                DisplacementAnalysis_App(app.validX, app.validY, app.stdX, app.stdY, app.corrCoef, '');
            else
                DisplacementAnalysis_App(app.validX, app.validY, app.stdX, app.stdY, app.corrCoef, app.WorkFolder.Value)
            end
        end

        % Button pushed function: DP1SeriesButton
        function DP1SeriesCallback(app, event)
            if ~isfolder(app.currentSeriesPath)
                msgbox('Select a path to a series first.')
                return
            end

            if ~isfile(fullfile(app.WorkFolder.Value,'error_strain.csv'))
                waitfor(msgbox('Uncertainty on strain file not found.'))
            end

            if ~isfile(fullfile(app.WorkFolder.Value,'uncertainty-normalized_depth.mat'))
                msgbox('Evaluate the uncertainty on the normalized depth first.')
                return
            end
            
            if app.xButton.Value
                direction = 'x';
            else
                direction = 'y';
            end

            DP_equibiaxial(app.SavingButton.Value, app.currentSeriesPath, direction, app.PillarDiameter.Value, app.LowerLimitDP.Value, app.UpperLimitDP.Value, ...
                app.StressLowerLimit.Value, app.StressHigherLimit.Value, app.PointsSpanSmoothing.Value, app.PolynomialDegreeSmoothing.Value, ...
                app.ElasticModulus.Value, app.PoissonRatio.Value)

            movegui(app.UIFigure,'onscreen')
        end

        % Button pushed function: Averaging1PillarButton
        function Averaging1PillarCallback(app, event)
            if isfolder(app.currentPillarPath)
                averaging_1_pillar(app.SavingButton.Value,app.currentPillarPath,app.PillarDiameter.Value, app.LowerLimitDP.Value, ...
                    app.UpperLimitDP.Value, app.StressLowerLimit.Value, app.StressHigherLimit.Value, app.DisplayIndividualPlotsCheckbox.Value)
            else
                msgbox('Select a path to a pillar first.')
                return
            end
        end

        % Button pushed function: StringSampleButton
        function StringSampleCallback(app, event)
            if isfolder(app.currentSamplePath)
                if app.SuccessivePlots.Value
                    string_plots_together_succession(app.SavingButton.Value, app.currentSamplePath, app.StressLowerLimit.Value, ...
                        app.StressHigherLimit.Value)
                else
                    string_plots_together_combined(app.SavingButton.Value, app.currentSamplePath, app.StressLowerLimit.Value, ...
                        app.StressHigherLimit.Value)
                end
            else
                msgbox('Select a path to a sample first.')
                return
            end
        end

        % Value changed function: StressHigherLimit, StressLowerLimit
        function CheckLowerInfHigherCallback(app, event)
            if app.StressHigherLimit.Value>app.StressLowerLimit.Value
                msgbox('Make sure the values of the boundary are in the right order.')
            end
        end

        % Value changed function: FileNamesRootEditField, 
        % ...and 4 other components
        function FirstLastImageFullNameCallback(app, event)
            formatString = sprintf('%%0%dd',app.NumberOfDigitsEditField.Value);
            firstImageNumberAllDigits = sprintf(formatString,app.FirstImageNumberEditField.Value);
            app.FirstImageFullNameEditField.Value = sprintf('%s%s.%s',app.FileNamesRootEditField.Value, ...
                firstImageNumberAllDigits,app.ImagesFormatDropDown.Value);
            lastImageNumberAllDigits = sprintf(formatString,app.FirstImageNumberEditField.Value+app.NumberOfImagesEditField.Value-1);
            app.LastImageFullNameEditField.Value = sprintf('%s%s.%s',app.FileNamesRootEditField.Value, ...
                lastImageNumberAllDigits,app.ImagesFormatDropDown.Value);
        end

        % Button pushed function: BrowseButtonSample1
        function BrowseSample1Callback(app, event)
            app.sample1Path = uigetdir('', "Select the series directory.");

            if app.sample1Path == 0 | isempty(app.sample1Path)
                disp('No directory selected.')
                return
            end
            cd(app.sample1Path)
            [~, app.Sample1EditField.Value] = fileparts(app.sample1Path);
            figure(app.UIFigure)
        end

        % Button pushed function: BrowseButtonSample2
        function BrowseSample2Callback(app, event)
            app.sample2Path = uigetdir('', "Select the series directory.");

            if app.sample2Path == 0 | isempty(app.sample2Path)
                disp('No directory selected. Stopping the execution.')
                return
            end
            cd(app.sample2Path)
            [~, app.Sample2EditField.Value] = fileparts(app.sample2Path);
            figure(app.UIFigure)
        end

        % Button pushed function: CompareButton
        function CompareSamplesCallback(app, event)
            if strcmp(app.savingPath,'')
                msgbox('No saving path selected.')
            else
                compare2Samples(app.SavingButton.Value,app.savingPath,app.ComparisonFilenameEditField.Value,app.FullComparison.Value,app.PlotOptions2.SelectedObject.Text, ...
                    app.PillarDiameterComparison.Value,app.sample1Path,app.sample2Path,app.Sample1EditField.Value,app.Sample2EditField.Value, ...
                    app.Namecurve1EditField.Value,app.Namecurve2EditField.Value)
            end
        end

        % Selection changed function: PlotOptions2
        function LinkingButtons2Callback(app, event)
            app.SuccessivePlots.Value = app.SuccessivePlots2.Value;
        end

        % Selection changed function: PlotOptions
        function LinkingButtonsCallback(app, event)
            app.SuccessivePlots2.Value = app.SuccessivePlots.Value;
        end

        % Button pushed function: BrowseButtonSample2_2
        function BrowseSavingFieldComparison(app, event)
            app.savingPath = uigetdir('', "Select the series directory.");

            if app.savingPath == 0 | isempty(app.savingPath)
                disp('No directory selected.')
                return
            end
            app.SavingdirectoryEditField.Value = app.savingPath;
            figure(app.UIFigure)
        end

        % Selection changed function: DirectionButtonGroup
        function ChangeDirectionCallback(app, event)
            if app.xButton.Value
                app.WorkFolder.Value = fullfile(app.currentSeriesPath,'averaged','x');
            else
                app.WorkFolder.Value = fullfile(app.currentSeriesPath,'averaged','y');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataSelectionTab = uitab(app.TabGroup);
            app.DataSelectionTab.Title = 'Data selection';
            app.SelectedseriesLabel = uilabel(app.DataSelectionTab);
            app.SelectedseriesLabel.Text = 'Current sample';
            app.WorkFolder = uieditfield(app.DataSelectionTab, 'text');
            app.NumberofimagespermillingstepEditFieldLabel = uilabel(app.DataSelectionTab);
            app.NumberofimagespermillingstepEditFieldLabel.Text = 'Number of images per milling step';
            app.NbImagesStep = uieditfield(app.DataSelectionTab, 'numeric');
            app.NbImagesStep.Limits = [0 Inf];
            app.NbImagesStep.Value = 5;
            app.StackpretreatmentButton = uibutton(app.DataSelectionTab, 'push');
            app.StackpretreatmentButton.ButtonPushedFcn = createCallbackFcn(app, @StackPretreatmentCallback, true);
            app.StackpretreatmentButton.Text = 'Stack pretreatment';
            app.CurrentpillarLabel = uilabel(app.DataSelectionTab);
            app.CurrentpillarLabel.Text = 'Current pillar';
            app.BrowseCurrentSeriesButton = uibutton(app.DataSelectionTab, 'push');
            app.BrowseCurrentSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCurrentSeriesCallback, true);
            app.BrowseCurrentSeriesButton.Text = 'Browse...';
            app.CurrentSeriesEditField = uieditfield(app.DataSelectionTab, 'text');
            app.CurrentSeriesEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentSeriesManualChangeCallback, true);
            app.BrowseCurrentPillarButton = uibutton(app.DataSelectionTab, 'push');
            app.BrowseCurrentPillarButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCurrentPillarCallback, true);
            app.BrowseCurrentPillarButton.Text = 'Browse...';
            app.CurrentPillarEditField = uieditfield(app.DataSelectionTab, 'text');
            app.CurrentPillarEditField.Editable = 'off';
            app.BrowseCurrentSampleButton = uibutton(app.DataSelectionTab, 'push');
            app.BrowseCurrentSampleButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCurrentSampleCallback, true);
            app.BrowseCurrentSampleButton.Text = 'Browse...';
            app.CurrentSampleEditField = uieditfield(app.DataSelectionTab, 'text');
            app.CurrentSampleEditField.Editable = 'off';
            app.CurrentseriesLabel = uilabel(app.DataSelectionTab);
            app.CurrentseriesLabel.Text = 'Current series';
            app.WorkfolderLabel = uilabel(app.DataSelectionTab);
            app.WorkfolderLabel.Text = 'Work folder';
            app.DirectionButtonGroup = uibuttongroup(app.DataSelectionTab);
            app.DirectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChangeDirectionCallback, true);
            app.DirectionButtonGroup.Title = 'Direction';
            app.yButton = uiradiobutton(app.DirectionButtonGroup);
            app.yButton.Text = 'y';
            app.xButton = uiradiobutton(app.DirectionButtonGroup);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.DICTab = uitab(app.TabGroup);
            app.DICTab.Title = 'Digital Image Correlation';
            app.DisplacementAnalysisButton = uibutton(app.DICTab, 'push');
            app.DisplacementAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @DisplacementAnalysisCallback, true);
            app.DisplacementAnalysisButton.Text = '3. Displacement analysis';
            app.DisplacementType = uidropdown(app.DICTab);
            app.DisplacementType.Items = {'Large displacements (vectorial)', 'Large displacements (scalar)', 'Small displacements'};
            app.DisplacementType.Value = 'Large displacements (vectorial)';
            app.ReferenceImage = uidropdown(app.DICTab);
            app.ReferenceImage.Items = {'First', 'Previous'};
            app.ReferenceImage.Value = 'First';
            app.CorrelationsizeEditFieldLabel = uilabel(app.DICTab);
            app.CorrelationsizeEditFieldLabel.Text = 'Correlation size';
            app.ReductionfactorEditFieldLabel = uilabel(app.DICTab);
            app.ReductionfactorEditFieldLabel.Text = 'Reduction factor';
            app.ProcessingmodeDropDownLabel = uilabel(app.DICTab);
            app.ProcessingmodeDropDownLabel.Text = 'Processing mode';
            app.ProcessingMode = uidropdown(app.DICTab);
            app.ProcessingMode.Items = {'Single', 'Multi', 'GPU'};
            app.ProcessingMode.Value = 'Single';
            app.ReductionFactor = uieditfield(app.DICTab, 'numeric');
            app.ReductionFactor.Value = 15;
            app.ReferenceimageDropDownLabel = uilabel(app.DICTab);
            app.ReferenceimageDropDownLabel.Text = 'Reference image';
            app.DisplacementtypeDropDownLabel = uilabel(app.DICTab);
            app.DisplacementtypeDropDownLabel.Text = 'Displacement type';
            app.FilenamesrootEditFieldLabel = uilabel(app.DICTab);
            app.FilenamesrootEditFieldLabel.Text = 'File names root';
            app.FirstimagenumberEditFieldLabel = uilabel(app.DICTab);
            app.FirstimagenumberEditFieldLabel.Text = 'First image number';
            app.CorrelationSize = uieditfield(app.DICTab, 'numeric');
            app.CorrelationSize.Value = 15;
            app.NumberofimagesEditFieldLabel = uilabel(app.DICTab);
            app.NumberofimagesEditFieldLabel.Text = 'Number of images';
            app.ProcessCorrelationsButton = uibutton(app.DICTab, 'push');
            app.ProcessCorrelationsButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessCorrelationsCallback, true);
            app.ProcessCorrelationsButton.Text = '2. Process correlations';
            app.NumberofdigitsEditFieldLabel = uilabel(app.DICTab);
            app.NumberofdigitsEditFieldLabel.Text = 'Number of digits';
            app.LastImageFullNameEditField = uieditfield(app.DICTab, 'text');
            app.LastImageFullNameEditField.Editable = 'off';
            app.LastImageFullNameEditField.Value = '019.tif';
            app.ImagesformatDropDownLabel = uilabel(app.DICTab);
            app.ImagesformatDropDownLabel.Text = 'Images format';
            app.FirstImageFullNameEditField = uieditfield(app.DICTab, 'text');
            app.FirstImageFullNameEditField.Editable = 'off';
            app.FirstImageFullNameEditField.Value = '000.tif';
            app.ImagesFormatDropDown = uidropdown(app.DICTab);
            app.ImagesFormatDropDown.Items = {'tif', 'tiff', 'bmp', 'jpg', 'jpeg', 'png'};
            app.ImagesFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @FirstLastImageFullNameCallback, true);
            app.ImagesFormatDropDown.Value = 'tif';
            app.NumberOfDigitsEditField = uieditfield(app.DICTab, 'numeric');
            app.NumberOfDigitsEditField.ValueChangedFcn = createCallbackFcn(app, @FirstLastImageFullNameCallback, true);
            app.NumberOfDigitsEditField.Value = 3;
            app.NumberOfImagesEditField = uieditfield(app.DICTab, 'numeric');
            app.NumberOfImagesEditField.ValueChangedFcn = createCallbackFcn(app, @FirstLastImageFullNameCallback, true);
            app.NumberOfImagesEditField.Value = 20;
            app.FirstImageNumberEditField = uieditfield(app.DICTab, 'numeric');
            app.FirstImageNumberEditField.ValueChangedFcn = createCallbackFcn(app, @FirstLastImageFullNameCallback, true);
            app.FirstimagefullnameEditFieldLabel = uilabel(app.DICTab);
            app.FirstimagefullnameEditFieldLabel.Text = 'First image full name';
            app.FileNamesRootEditField = uieditfield(app.DICTab, 'text');
            app.FileNamesRootEditField.ValueChangedFcn = createCallbackFcn(app, @FirstLastImageFullNameCallback, true);
            app.GenerateFileListButton = uibutton(app.DICTab, 'push');
            app.GenerateFileListButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFilelistCallback, true);
            app.GenerateFileListButton.Text = '1. Generate file list';
            app.LastimagefullnameLabel = uilabel(app.DICTab);
            app.LastimagefullnameLabel.Text = 'Last image full name';
            app.DepthProfilingTab = uitab(app.TabGroup);
            app.DepthProfilingTab.Title = 'Depth profiling';
            app.PoissonRatio = uieditfield(app.DepthProfilingTab, 'numeric');
            app.PoissonRatio.Value = 0.36;
            app.PillardiameterEditFieldLabel = uilabel(app.DepthProfilingTab);
            app.PillardiameterEditFieldLabel.Text = 'Pillar diameter';
            app.TheoreticalstepsizeEditFieldLabel = uilabel(app.DepthProfilingTab);
            app.TheoreticalstepsizeEditFieldLabel.Text = 'Theoretical step size';
            app.PillarreductionfactorEditFieldLabel = uilabel(app.DepthProfilingTab);
            app.PillarreductionfactorEditFieldLabel.Text = 'Pillar reduction factor';
            app.PointsspanLabel_2 = uilabel(app.DepthProfilingTab);
            app.PointsspanLabel_2.Text = 'Lower limit';
            app.UpperlimitLabel = uilabel(app.DepthProfilingTab);
            app.UpperlimitLabel.Text = 'Upper limit';
            app.PointsspanLabel = uilabel(app.DepthProfilingTab);
            app.PointsspanLabel.Text = 'Points span';
            app.PolynomialdegreeEditFieldLabel = uilabel(app.DepthProfilingTab);
            app.PolynomialdegreeEditFieldLabel.Text = 'Polynomial degree';
            app.ElasticmodulusGPaEditFieldLabel = uilabel(app.DepthProfilingTab);
            app.ElasticmodulusGPaEditFieldLabel.Text = 'Elastic modulus [GPa]';
            app.PoissonratioEditFieldLabel = uilabel(app.DepthProfilingTab);
            app.PoissonratioEditFieldLabel.Text = 'Poisson ratio';
            app.ElasticModulus = uieditfield(app.DepthProfilingTab, 'numeric');
            app.ElasticModulus.Value = 120;
            app.MaterialPropertiesLabel = uilabel(app.DepthProfilingTab);
            app.MaterialPropertiesLabel.Text = 'Material properties';
            app.PolynomialDegreeSmoothing = uieditfield(app.DepthProfilingTab, 'numeric');
            app.PolynomialDegreeSmoothing.Value = 3;
            app.PointsSpanSmoothing = uieditfield(app.DepthProfilingTab, 'numeric');
            app.PointsSpanSmoothing.Value = 6;
            app.DataSmoothingLabel = uilabel(app.DepthProfilingTab);
            app.DataSmoothingLabel.Text = 'Data smoothing parameters';
            app.UpperLimitDP = uieditfield(app.DepthProfilingTab, 'numeric');
            app.UpperLimitDP.Value = 0.2;
            app.LowerLimitDP = uieditfield(app.DepthProfilingTab, 'numeric');
            app.LowerLimitDP.Value = 0.05;
            app.LimitsDPLabel = uilabel(app.DepthProfilingTab);
            app.LimitsDPLabel.Text = 'Limits for the depth profiling';
            app.PillarReductionFactor = uieditfield(app.DepthProfilingTab, 'numeric');
            app.PillarReductionFactor.Value = 0.8;
            app.DICparametersLabel = uilabel(app.DepthProfilingTab);
            app.DICparametersLabel.Text = 'DIC parameters';
            app.TheoreticalStepSize = uieditfield(app.DepthProfilingTab, 'numeric');
            app.PillarDiameter = uieditfield(app.DepthProfilingTab, 'numeric');
            app.PillarDiameter.Value = 10;
            app.GeometricalPropertiesLabel = uilabel(app.DepthProfilingTab);
            app.GeometricalPropertiesLabel.Text = 'Geometrical properties of the pillar';
            app.StringSampleButton = uibutton(app.DepthProfilingTab, 'push');
            app.StringSampleButton.ButtonPushedFcn = createCallbackFcn(app, @StringSampleCallback, true);
            app.StringSampleButton.Text = 'String together for one sample';
            app.StressHigherLimit = uieditfield(app.DepthProfilingTab, 'numeric');
            app.StressHigherLimit.AllowEmpty = 'on';
            app.StressHigherLimit.ValueChangedFcn = createCallbackFcn(app, @CheckLowerInfHigherCallback, true);
            app.StressHigherLimit.Value = [];
            app.StressLowerLimit = uieditfield(app.DepthProfilingTab, 'numeric');
            app.StressLowerLimit.AllowEmpty = 'on';
            app.StressLowerLimit.ValueChangedFcn = createCallbackFcn(app, @CheckLowerInfHigherCallback, true);
            app.StressLowerLimit.Value = [];
            app.StressLimitsLabel = uilabel(app.DepthProfilingTab);
            app.StressLimitsLabel.Text = 'Stress limits (MPa)';
            app.DisplayIndividualPlotsCheckbox = uicheckbox(app.DepthProfilingTab);
            app.DisplayIndividualPlotsCheckbox.Text = 'Display individual plots';
            app.Averaging1PillarButton = uibutton(app.DepthProfilingTab, 'push');
            app.Averaging1PillarButton.ButtonPushedFcn = createCallbackFcn(app, @Averaging1PillarCallback, true);
            app.Averaging1PillarButton.Text = 'Averaging for one pillar diameter';
            app.DP1SeriesButton = uibutton(app.DepthProfilingTab, 'push');
            app.DP1SeriesButton.ButtonPushedFcn = createCallbackFcn(app, @DP1SeriesCallback, true);
            app.DP1SeriesButton.Text = 'DP of one series (equibiaxial)';
            app.UncertaintyNormalizedDepthCalculation = uibutton(app.DepthProfilingTab, 'push');
            app.UncertaintyNormalizedDepthCalculation.ButtonPushedFcn = createCallbackFcn(app, @UncertaintyNormalizedDepthCalculationCallback, true);
            app.UncertaintyNormalizedDepthCalculation.Text = 'Uncertainty on normalized depth calculation';
            app.PlotOptions = uibuttongroup(app.DepthProfilingTab);
            app.PlotOptions.SelectionChangedFcn = createCallbackFcn(app, @LinkingButtonsCallback, true);
            app.PlotOptions.Title = 'Options';
            app.CombinedPlots = uiradiobutton(app.PlotOptions);
            app.CombinedPlots.Tooltip = {'Combination of all the data together in one plot'};
            app.CombinedPlots.Text = 'Combination';
            app.SuccessivePlots = uiradiobutton(app.PlotOptions);
            app.SuccessivePlots.Tooltip = {'Successive plots for each pillar diameter'};
            app.SuccessivePlots.Text = 'Succession';
            app.SuccessivePlots.Value = true;
            app.LowerlimitLabel = uilabel(app.DepthProfilingTab);
            app.LowerlimitLabel.Text = 'Lower limit';
            app.HigherlimitLabel = uilabel(app.DepthProfilingTab);
            app.HigherlimitLabel.Text = 'Higher limit';
            app.SamplesComparisonTab = uitab(app.TabGroup);
            app.SamplesComparisonTab.Title = 'Samples comparison';
            app.CompareButton = uibutton(app.SamplesComparisonTab, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareSamplesCallback, true);
            app.CompareButton.Text = 'Compare';
            app.Sample1EditFieldLabel = uilabel(app.SamplesComparisonTab);
            app.Sample1EditFieldLabel.Text = 'Sample 1';
            app.BrowseButtonSample2 = uibutton(app.SamplesComparisonTab, 'push');
            app.BrowseButtonSample2.ButtonPushedFcn = createCallbackFcn(app, @BrowseSample2Callback, true);
            app.BrowseButtonSample2.Text = 'Browse...';
            app.Sample2EditFieldLabel = uilabel(app.SamplesComparisonTab);
            app.Sample2EditFieldLabel.Text = 'Sample 2';
            app.Sample2EditField = uieditfield(app.SamplesComparisonTab, 'text');
            app.BrowseButtonSample1 = uibutton(app.SamplesComparisonTab, 'push');
            app.BrowseButtonSample1.ButtonPushedFcn = createCallbackFcn(app, @BrowseSample1Callback, true);
            app.BrowseButtonSample1.Text = 'Browse...';
            app.Sample1EditField = uieditfield(app.SamplesComparisonTab, 'text');
            app.PlotOptions3 = uibuttongroup(app.SamplesComparisonTab);
            app.PlotOptions3.Title = 'Options';
            app.NotFullComparison = uiradiobutton(app.PlotOptions3);
            app.NotFullComparison.Tooltip = {'Combination of all the data together in one plot'};
            app.NotFullComparison.Text = '1 pillar diameter';
            app.NotFullComparison.Value = true;
            app.FullComparison = uiradiobutton(app.PlotOptions3);
            app.FullComparison.Tooltip = {'Successive plots for each pillar diameter'};
            app.FullComparison.Text = 'Full comparison';
            app.PlotOptions2 = uibuttongroup(app.SamplesComparisonTab);
            app.PlotOptions2.SelectionChangedFcn = createCallbackFcn(app, @LinkingButtons2Callback, true);
            app.PlotOptions2.Title = 'Options for full comparison';
            app.CombinedPlots2 = uiradiobutton(app.PlotOptions2);
            app.CombinedPlots2.Tooltip = {'Combination of all the data together in one plot'};
            app.CombinedPlots2.Text = 'Combination';
            app.SuccessivePlots2 = uiradiobutton(app.PlotOptions2);
            app.SuccessivePlots2.Tooltip = {'Successive plots for each pillar diameter'};
            app.SuccessivePlots2.Text = 'Succession';
            app.SuccessivePlots2.Value = true;
            app.PillardiameterEditFieldLabel_2 = uilabel(app.SamplesComparisonTab);
            app.PillardiameterEditFieldLabel_2.Text = 'Pillar diameter ';
            app.PillarDiameterComparison = uieditfield(app.SamplesComparisonTab, 'numeric');
            app.PillarDiameterComparison.Value = 10;
            app.SavingdirectoryEditFieldLabel = uilabel(app.SamplesComparisonTab);
            app.SavingdirectoryEditFieldLabel.Text = 'Saving directory';
            app.SavingdirectoryEditField = uieditfield(app.SamplesComparisonTab, 'text');
            app.BrowseButtonSample2_2 = uibutton(app.SamplesComparisonTab, 'push');
            app.BrowseButtonSample2_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseSavingFieldComparison, true);
            app.BrowseButtonSample2_2.Text = 'Browse...';
            app.Namecurve1EditFieldLabel = uilabel(app.SamplesComparisonTab);
            app.Namecurve1EditFieldLabel.Text = 'Name curve 1';
            app.Namecurve1EditField = uieditfield(app.SamplesComparisonTab, 'text');
            app.Namecurve2EditFieldLabel = uilabel(app.SamplesComparisonTab);
            app.Namecurve2EditFieldLabel.Text = 'Name curve 2';
            app.Namecurve2EditField = uieditfield(app.SamplesComparisonTab, 'text');
            app.FilenameEditFieldLabel = uilabel(app.SamplesComparisonTab);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.ComparisonFilenameEditField = uieditfield(app.SamplesComparisonTab, 'text');
            app.CloseAllButton = uibutton(app.UIFigure, 'push');
            app.CloseAllButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllCallback, true);
            app.CloseAllButton.Text = 'Close all windows';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Depth profiling of residual stress';
            app.SavingButton = uibutton(app.UIFigure, 'state');
            app.SavingButton.ValueChangedFcn = createCallbackFcn(app, @SavingButtonCallback, true);
            app.SavingButton.Text = 'Saving off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DP_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @AppStartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end