classdef DataSelectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataSelectionUIFigure     matlab.ui.Figure
        FilestructureLabel        matlab.ui.control.Label
        FileTree                  matlab.ui.container.Tree
        sampleNode                matlab.ui.container.TreeNode
        pillarNode                matlab.ui.container.TreeNode
        series1Node               matlab.ui.container.TreeNode
        rawNode                   matlab.ui.container.TreeNode
        ZeroNode                  matlab.ui.container.TreeNode
        FourtyFiveNode            matlab.ui.container.TreeNode
        NinetyNode                matlab.ui.container.TreeNode
        TherawimagesareinthefollowingdirectoryEditFieldLabel  matlab.ui.control.Label
        CurrentseriesLabel        matlab.ui.control.Label
        SamplePathEditField       matlab.ui.control.EditField
        BrowseSampleButton        matlab.ui.control.Button
        PillarPathEditField       matlab.ui.control.EditField
        CurrentpillarLabel        matlab.ui.control.Label
        BrowsePillarButton        matlab.ui.control.Button
        SelectedseriesLabel       matlab.ui.control.Label
        SeriesPathEditField       matlab.ui.control.EditField
        BrowseSeriesButton        matlab.ui.control.Button
        ScanDirectionButtonGroup  matlab.ui.container.ButtonGroup
        ZeroButton                matlab.ui.control.RadioButton
        FourtyFiveButton          matlab.ui.control.RadioButton
        NinetyButton              matlab.ui.control.RadioButton
        RawImagesDirEditField     matlab.ui.control.EditField
        ScanDirectionImage        matlab.ui.control.Image
        OKButton                  matlab.ui.control.Button
        CancelButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        appPath string {mustBeFolder}

        samplePath
        pillarPath
        seriesPath
        scanDirection

        workfolder

        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, appPath, workfolder, seriesPath, pillarPath, samplePath, scanDirection)
            app.appPath = appPath;
            
            [~, app.SamplePathEditField.Value] = fileparts(samplePath);
            [~, app.PillarPathEditField.Value] = fileparts(pillarPath);
            [~, app.SeriesPathEditField.Value] = fileparts(seriesPath);
            
            app.samplePath = samplePath;
            app.pillarPath = pillarPath;
            app.seriesPath = seriesPath;
            
            switch scanDirection
                case 0
                    app.ZeroButton.Value = true;
                    app.scanDirection = 0;
                case 45
                    app.FourtyFiveButton.Value = true;
                    app.scanDirection = 45;
                case 90
                    app.NinetyButton.Value = true;
                    app.scanDirection = 90;
            end
            
            if ~isempty(app.seriesPath)
                app.RawImagesDirEditField.Value = fullfile(app.seriesPath,'raw',num2str(app.scanDirection));
            end

            % Enable scan direction change if a series path is provided
            if ~isempty(app.SeriesPathEditField.Value)
                app.ScanDirectionButtonGroup.Enable = "on";
                app.ZeroButton.Enable = "on";
                app.FourtyFiveButton.Enable = "on";
                app.NinetyButton.Enable = "on";
            end

            app.workfolder = workfolder;

            app.mainapp = mainapp;

            % Extend tree
            expand(app.FileTree,'all')
        end

        % Button pushed function: BrowseSampleButton
        function BrowseSampleCallback(app, event)
            app.samplePath = uigetdir('', "Select the sample directory.");

            if app.samplePath == 0 | isempty(app.samplePath)
                return
            end

            cd(app.samplePath)
            
            [~, app.SamplePathEditField.Value] = fileparts(app.samplePath);
            app.PillarPathEditField.Value = '';
            app.SeriesPathEditField.Value = '';
            app.pillarPath = '';
            app.seriesPath = '';

            app.RawImagesDirEditField.Value = '';
            app.workfolder = app.samplePath;

            % Disable scan direction
            app.ScanDirectionButtonGroup.Enable = "off";
        end

        % Button pushed function: BrowsePillarButton
        function BrowsePillarCallback(app, event)
            app.pillarPath = uigetdir('', "Select the pillar directory.");

            if app.pillarPath == 0 | isempty(app.pillarPath)
                return
            end

            cd(app.pillarPath)

            [app.samplePath, app.PillarPathEditField.Value] = fileparts(app.pillarPath);
            [~, app.SamplePathEditField.Value] = fileparts(app.samplePath);
            app.SeriesPathEditField.Value = '';
            app.seriesPath = '';

            app.RawImagesDirEditField.Value = '';
            app.workfolder = app.pillarPath;

            % Disable scan direction
            app.ScanDirectionButtonGroup.Enable = "off";
        end

        % Button pushed function: BrowseSeriesButton
        function BrowseSeriesCallback(app, event)
            app.seriesPath = uigetdir('','Select the series directory.');

            if app.seriesPath == 0 | isempty(app.seriesPath)
                return
            end

            cd(app.seriesPath)

            [app.pillarPath, app.SeriesPathEditField.Value] = fileparts(app.seriesPath);
            [~,app.PillarPathEditField.Value] = fileparts(app.pillarPath);
            
            [app.samplePath,~] = fileparts(app.pillarPath);
            [~,app.SamplePathEditField.Value] = fileparts(app.samplePath);

            app.RawImagesDirEditField.Value = fullfile(app.seriesPath,'raw',num2str(app.scanDirection));
            app.workfolder = fullfile(app.seriesPath,'processed',num2str(app.scanDirection));

            % Enable scan direction
            app.ScanDirectionButtonGroup.Enable = "on";
            app.ZeroButton.Enable = "on";
            app.FourtyFiveButton.Enable = "on";
            app.NinetyButton.Enable = "on";
        end

        % Selection changed function: ScanDirectionButtonGroup
        function ImagesDirectionChangeCallback(app, event)
            if app.ZeroButton.Value == true
                app.scanDirection = 0;
                app.ScanDirectionImage.ImageSource = fullfile(app.appPath, 'rsdp_resources/pillar_directions_0.png');
            elseif app.FourtyFiveButton.Value == true
                app.scanDirection = 45;
                app.ScanDirectionImage.ImageSource = fullfile(app.appPath, 'rsdp_resources/pillar_directions_45.png');
            else
                app.scanDirection = 90;
                app.ScanDirectionImage.ImageSource = fullfile(app.appPath, 'rsdp_resources/pillar_directions_90.png');
            end

            app.RawImagesDirEditField.Value = fullfile(app.seriesPath,'raw',num2str(app.scanDirection));
        end

        % Button pushed function: OKButton
        function OKCallback(app, event)
            % Check if selected folder exists
            if ~isempty(app.SeriesPathEditField.Value) & ~exist(app.RawImagesDirEditField.Value,"dir")
                invalidPathAlert = uiconfirm(app.DataSelectionUIFigure,"The selected directory for the raw images does not exist.","Invalid path", ...
                    "Options","OK","Icon","warning");
                waitfor(invalidPathAlert)
                return
            end
            
            app.mainapp.samplePath = app.samplePath;
            app.mainapp.pillarPath = app.pillarPath;
            app.mainapp.seriesPath = app.seriesPath;

            if app.ZeroButton.Value == true
                app.mainapp.scanDirection = 0;
            elseif app.FourtyFiveButton.Value == true
                app.mainapp.scanDirection = 45;
            else
                app.mainapp.scanDirection = 90;
            end
            
            app.mainapp.workFolder = app.workfolder;

            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelCallback(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DataSelectionUIFigure = uifigure('Visible', 'off');
            app.DataSelectionUIFigure.Name = 'Data selection';
            app.DataSelectionUIFigure.Icon = fullfile(pathToMLAPP, 'rsdp_resources', 'icon-rsdp.png');
            app.CancelButton = uibutton(app.DataSelectionUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCallback, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.DataSelectionUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKCallback, true);
            app.OKButton.Text = 'OK';
            app.ScanDirectionImage = uiimage(app.DataSelectionUIFigure);
            app.ScanDirectionImage.ImageSource = fullfile(pathToMLAPP, 'rsdp_resources', 'pillar_directions_0.png');
            app.RawImagesDirEditField = uieditfield(app.DataSelectionUIFigure, 'text');
            app.RawImagesDirEditField.Editable = 'off';
            app.ScanDirectionButtonGroup = uibuttongroup(app.DataSelectionUIFigure);
            app.ScanDirectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImagesDirectionChangeCallback, true);
            app.ScanDirectionButtonGroup.Enable = 'off';
            app.ScanDirectionButtonGroup.Title = 'Scan direction';
            app.NinetyButton = uiradiobutton(app.ScanDirectionButtonGroup);
            app.NinetyButton.Enable = 'off';
            app.NinetyButton.Text = '90°';
            app.FourtyFiveButton = uiradiobutton(app.ScanDirectionButtonGroup);
            app.FourtyFiveButton.Enable = 'off';
            app.FourtyFiveButton.Text = '45°';
            app.ZeroButton = uiradiobutton(app.ScanDirectionButtonGroup);
            app.ZeroButton.Enable = 'off';
            app.ZeroButton.Text = '0°';
            app.ZeroButton.Value = true;
            app.BrowseSeriesButton = uibutton(app.DataSelectionUIFigure, 'push');
            app.BrowseSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseSeriesCallback, true);
            app.BrowseSeriesButton.Text = 'Browse...';
            app.SeriesPathEditField = uieditfield(app.DataSelectionUIFigure, 'text');
            app.SeriesPathEditField.Editable = 'off';
            app.SelectedseriesLabel = uilabel(app.DataSelectionUIFigure);
            app.SelectedseriesLabel.Text = 'Sample directory';
            app.BrowsePillarButton = uibutton(app.DataSelectionUIFigure, 'push');
            app.BrowsePillarButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsePillarCallback, true);
            app.BrowsePillarButton.Text = 'Browse...';
            app.CurrentpillarLabel = uilabel(app.DataSelectionUIFigure);
            app.CurrentpillarLabel.Text = 'Pillar directory';
            app.PillarPathEditField = uieditfield(app.DataSelectionUIFigure, 'text');
            app.PillarPathEditField.Editable = 'off';
            app.BrowseSampleButton = uibutton(app.DataSelectionUIFigure, 'push');
            app.BrowseSampleButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseSampleCallback, true);
            app.BrowseSampleButton.Text = 'Browse...';
            app.SamplePathEditField = uieditfield(app.DataSelectionUIFigure, 'text');
            app.SamplePathEditField.Editable = 'off';
            app.CurrentseriesLabel = uilabel(app.DataSelectionUIFigure);
            app.CurrentseriesLabel.Text = 'Series directory';
            app.TherawimagesareinthefollowingdirectoryEditFieldLabel = uilabel(app.DataSelectionUIFigure);
            app.TherawimagesareinthefollowingdirectoryEditFieldLabel.Text = 'The raw images are in the following directory:';
            app.FileTree = uitree(app.DataSelectionUIFigure);
            app.sampleNode = uitreenode(app.FileTree);
            app.sampleNode.Text = 'sample';
            app.pillarNode = uitreenode(app.sampleNode);
            app.pillarNode.Text = 'pillar';
            app.series1Node = uitreenode(app.pillarNode);
            app.series1Node.Text = 'series1';
            app.rawNode = uitreenode(app.series1Node);
            app.rawNode.Text = 'raw';
            app.ZeroNode = uitreenode(app.rawNode);
            app.ZeroNode.Text = '0';
            app.FourtyFiveNode = uitreenode(app.rawNode);
            app.FourtyFiveNode.Text = '45';
            app.NinetyNode = uitreenode(app.rawNode);
            app.NinetyNode.Text = '90';
            app.FilestructureLabel = uilabel(app.DataSelectionUIFigure);
            app.FilestructureLabel.Text = 'File structure';
            % Show the figure after all components are created
            app.DataSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataSelectionApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DataSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.DataSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataSelectionUIFigure)
        end
    end
end