classdef GenerateFileListParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                      matlab.ui.Figure
        FileNamesRootEditField          matlab.ui.control.EditField
        FirstImageNumberEditField       matlab.ui.control.NumericEditField
        NumberOfImagesEditField         matlab.ui.control.NumericEditField
        NumberOfDigitsEditField         matlab.ui.control.NumericEditField
        ImagesFormatDropDown            matlab.ui.control.DropDown
        LastimagefullnameLabel          matlab.ui.control.Label
        FirstImageFullNameEditField     matlab.ui.control.EditField
        LastImageFullNameEditField      matlab.ui.control.EditField
        OKButton                        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        FirstimagefullnameEditFieldLabel  matlab.ui.control.Label
        NumberofdigitsEditFieldLabel    matlab.ui.control.Label
        ImagesformatDropDownLabel       matlab.ui.control.Label
        FirstimagenumberEditFieldLabel  matlab.ui.control.Label
        NumberofimagesEditFieldLabel    matlab.ui.control.Label
        RootLabel                       matlab.ui.control.Label
    end

      
    properties (Access = private)
        root
        firstImgNb
        nbImages
        nbDigits
        imgFormat

        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, root, firstImgNb, nbImages, nbDigits, imgFormat)
            % Get parameters from main app
            app.FileNamesRootEditField.Value = root;
            app.FirstImageNumberEditField.Value = firstImgNb;
            app.NumberOfImagesEditField.Value = nbImages;
            app.NumberOfDigitsEditField.Value = nbDigits;
            app.ImagesFormatDropDown.Value = imgFormat;
            
            % Initialize first/last image name
            FirstLastImageFullNameCallback(app)

            app.mainapp = mainapp;
        end

        % Button pushed function: OKButton
        function OKCallback(app, event)
            % Pass back parameters to main app
            app.mainapp.root = app.FileNamesRootEditField.Value;
            app.mainapp.firstImgNb = app.FirstImageNumberEditField.Value;
            app.mainapp.nbImages = app.NumberOfImagesEditField.Value;
            app.mainapp.nbDigits = app.NumberOfDigitsEditField.Value;
            app.mainapp.imgFormat = app.ImagesFormatDropDown.Value;
            
            delete(app)
        end

        % Value changed function: FileNamesRootEditField, 
        % ...and 4 other components
        function FirstLastImageFullNameCallback(app, event)
            formatString = sprintf('%%0%dd',app.NumberOfDigitsEditField.Value);
            firstImageNumberAllDigits = sprintf(formatString,app.FirstImageNumberEditField.Value);
            app.FirstImageFullNameEditField.Value = sprintf('%s%s.%s',app.FileNamesRootEditField.Value, ...
                firstImageNumberAllDigits,app.ImagesFormatDropDown.Value);
            lastImageNumberAllDigits = sprintf(formatString,app.FirstImageNumberEditField.Value+app.NumberOfImagesEditField.Value-1);
            app.LastImageFullNameEditField.Value = sprintf('%s%s.%s',app.FileNamesRootEditField.Value, ...
                lastImageNumberAllDigits,app.ImagesFormatDropDown.Value);
        end

        % Button pushed function: CancelButton
        function CancelCallback(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'File list parameters';
            app.MainWindow.Icon = fullfile(pathToMLAPP, 'DP_app_resources', 'icon-DP_app.png');
            app.RootLabel = uilabel(app.MainWindow);
            app.RootLabel.Text = 'Root';
            app.NumberofimagesEditFieldLabel = uilabel(app.MainWindow);
            app.NumberofimagesEditFieldLabel.Text = 'Number of images';
            app.FirstimagenumberEditFieldLabel = uilabel(app.MainWindow);
            app.FirstimagenumberEditFieldLabel.Text = 'First image number';
            app.ImagesformatDropDownLabel = uilabel(app.MainWindow);
            app.ImagesformatDropDownLabel.Text = 'Images format';
            app.NumberofdigitsEditFieldLabel = uilabel(app.MainWindow);
            app.NumberofdigitsEditFieldLabel.Text = 'Number of digits';
            app.FirstimagefullnameEditFieldLabel = uilabel(app.MainWindow);
            app.FirstimagefullnameEditFieldLabel.Text = 'First image full name';
            app.CancelButton = uibutton(app.MainWindow, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCallback, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.MainWindow, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKCallback, true);
            app.OKButton.Text = 'OK';
            app.LastImageFullNameEditField = uieditfield(app.MainWindow, 'text');
            app.LastImageFullNameEditField.Editable = 'off';
            app.LastImageFullNameEditField.Value = '019.tif';
            app.FirstImageFullNameEditField = uieditfield(app.MainWindow, 'text');
            app.FirstImageFullNameEditField.Editable = 'off';
            app.FirstImageFullNameEditField.Value = '000.tif';
            app.LastimagefullnameLabel = uilabel(app.MainWindow);
            app.LastimagefullnameLabel.Text = 'Last image full name';
            app.ImagesFormatDropDown = uidropdown(app.MainWindow);
            app.ImagesFormatDropDown.Items = {'tif', 'tiff', 'bmp', 'jpg', 'jpeg', 'png'};
            app.ImagesFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @FirstLastImageFullNameCallback, true);
            app.ImagesFormatDropDown.Value = 'tif';
            app.NumberOfDigitsEditField = uieditfield(app.MainWindow, 'numeric');
            app.NumberOfDigitsEditField.ValueChangedFcn = createCallbackFcn(app, @FirstLastImageFullNameCallback, true);
            app.NumberOfDigitsEditField.Value = 3;
            app.NumberOfImagesEditField = uieditfield(app.MainWindow, 'numeric');
            app.NumberOfImagesEditField.ValueChangedFcn = createCallbackFcn(app, @FirstLastImageFullNameCallback, true);
            app.NumberOfImagesEditField.Value = 20;
            app.FirstImageNumberEditField = uieditfield(app.MainWindow, 'numeric');
            app.FirstImageNumberEditField.ValueChangedFcn = createCallbackFcn(app, @FirstLastImageFullNameCallback, true);
            app.FileNamesRootEditField = uieditfield(app.MainWindow, 'text');
            app.FileNamesRootEditField.ValueChangedFcn = createCallbackFcn(app, @FirstLastImageFullNameCallback, true);
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GenerateFileListParameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end