classdef ImageProcessingParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow              matlab.ui.Figure
        ImageRotationEditField  matlab.ui.control.DropDown
        RotationoftheimagesDropDownLabel  matlab.ui.control.Label
        NumberofimagespermillingstepLabel  matlab.ui.control.Label
        ImageWidthEditField     matlab.ui.control.NumericEditField
        ImageHeightEditField    matlab.ui.control.NumericEditField
        ImageheightpxLabel      matlab.ui.control.Label
        ImagewidthpxLabel       matlab.ui.control.Label
        Filter1DropDown         matlab.ui.control.DropDown
        Filter2DropDown         matlab.ui.control.DropDown
        Filter2DropDownLabel    matlab.ui.control.Label
        NbImagesStep            matlab.ui.control.Spinner
        OKButton                matlab.ui.control.Button
        CancelButton            matlab.ui.control.Button
        Filter1DropDownLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        imageWidth
        imageHeight
        
        imgRotation

        filter1
        filter2
        
        nbImgsStep

        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, imageWidth, imageHeight, imgRotation, filter1, filter2, nbImagesStep)
            % Retrieve parameters from main app
            app.ImageWidthEditField.Value = imageWidth;
            app.ImageHeightEditField.Value = imageHeight;

            if app.imgRotation == 0
                app.ImageRotationEditField.Value = "0°";
            elseif app.imgRotation == 90
                app.ImageRotationEditField.Value = "90°";
            else
                app.ImageRotationEditField.Value = "45°";
            end

            app.Filter1DropDown.Value = filter1;
            app.Filter2DropDown.Value = filter2;
            
            app.NbImagesStep.Value = nbImagesStep;

            FiltersOptionsCallback(app)

            app.mainapp = mainapp;
        end

        % Button pushed function: OKButton
        function OKCallback(app, event)
            app.mainapp.imageWidth = app.ImageWidthEditField.Value;
            app.mainapp.imageHeight = app.ImageHeightEditField.Value;
            
            app.mainapp.imgRotation = app.ImageRotationEditField.Value;

            app.mainapp.filter1 = app.Filter1DropDown.Value;
            app.mainapp.filter2 = app.Filter2DropDown.Value;

            app.mainapp.nbImagesStep = app.NbImagesStep.Value;
            
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelCallback(app, event)
            delete(app)
        end

        % Value changed function: Filter1DropDown, Filter2DropDown
        function FiltersOptionsCallback(app, event)
            if app.Filter1DropDown.Value == "Intensity averaging" || app.Filter2DropDown.Value == "Intensity averaging"
                app.NbImagesStep.Enable = "on";
            else
                app.NbImagesStep.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Image processing parameters';
            app.MainWindow.Icon = fullfile(pathToMLAPP, 'DP_app_resources', 'icon-DP_app.png');
            app.Filter1DropDownLabel = uilabel(app.MainWindow);
            app.Filter1DropDownLabel.Text = 'Filter 1';
            app.CancelButton = uibutton(app.MainWindow, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCallback, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.MainWindow, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKCallback, true);
            app.OKButton.Text = 'OK';
            app.NbImagesStep = uispinner(app.MainWindow);
            app.NbImagesStep.Limits = [1 100];
            app.NbImagesStep.Value = 5;
            app.Filter2DropDownLabel = uilabel(app.MainWindow);
            app.Filter2DropDownLabel.Text = 'Filter 2';
            app.Filter2DropDown = uidropdown(app.MainWindow);
            app.Filter2DropDown.Items = {'Image registration (alignment)', 'Intensity averaging', 'None'};
            app.Filter2DropDown.ValueChangedFcn = createCallbackFcn(app, @FiltersOptionsCallback, true);
            app.Filter2DropDown.Value = 'Intensity averaging';
            app.Filter1DropDown = uidropdown(app.MainWindow);
            app.Filter1DropDown.Items = {'Image registration (alignment)', 'Intensity averaging', 'None'};
            app.Filter1DropDown.ValueChangedFcn = createCallbackFcn(app, @FiltersOptionsCallback, true);
            app.Filter1DropDown.Value = 'Image registration (alignment)';
            app.ImagewidthpxLabel = uilabel(app.MainWindow);
            app.ImagewidthpxLabel.Text = 'Image width (px)';
            app.ImageheightpxLabel = uilabel(app.MainWindow);
            app.ImageheightpxLabel.Text = 'Image height (px)';
            app.ImageHeightEditField = uieditfield(app.MainWindow, 'numeric');
            app.ImageHeightEditField.Limits = [0 Inf];
            app.ImageHeightEditField.Value = 884;
            app.ImageWidthEditField = uieditfield(app.MainWindow, 'numeric');
            app.ImageWidthEditField.Limits = [0 Inf];
            app.ImageWidthEditField.Value = 1024;
            app.NumberofimagespermillingstepLabel = uilabel(app.MainWindow);
            app.NumberofimagespermillingstepLabel.Text = 'Number of images per milling step';
            app.RotationoftheimagesDropDownLabel = uilabel(app.MainWindow);
            app.RotationoftheimagesDropDownLabel.Text = 'Rotation of the images';
            app.ImageRotationEditField = uidropdown(app.MainWindow);
            app.ImageRotationEditField.Items = {'0°', '90°', '45°'};
            app.ImageRotationEditField.Value = '0°';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageProcessingParameters(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainWindow)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MainWindow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end