classdef StringParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow       matlab.ui.Figure
        PlotOptions      matlab.ui.container.ButtonGroup
        SuccessivePlots  matlab.ui.control.RadioButton
        CombinedPlots    matlab.ui.control.RadioButton
        OKButton         matlab.ui.control.Button
        CancelButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        stringingMode
        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, stringingMode)
            if strcmp(stringingMode, "Combined")
                app.CombinedPlots.Value = true;
            else
                app.SuccessivePlots.Value = true;
            end

            app.mainapp = mainapp;
        end

        % Button pushed function: OKButton
        function OKCallback(app, event)
            if app.CombinedPlots.Value == true
                app.mainapp.stringingMode = "Combined";
            else
                app.mainapp.stringingMode = "Successive";
            end

            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelCallback(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Stringing parameters';
            app.MainWindow.Icon = fullfile(pathToMLAPP, 'DP_app_resources', 'icon-DP_app.png');
            app.CancelButton = uibutton(app.MainWindow, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCallback, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.MainWindow, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKCallback, true);
            app.OKButton.Text = 'OK';
            app.PlotOptions = uibuttongroup(app.MainWindow);
            app.PlotOptions.Title = 'Options';
            app.CombinedPlots = uiradiobutton(app.PlotOptions);
            app.CombinedPlots.Tooltip = {'Combination of all the data together in one plot'};
            app.CombinedPlots.Text = 'Combination';
            app.SuccessivePlots = uiradiobutton(app.PlotOptions);
            app.SuccessivePlots.Tooltip = {'Successive plots for each pillar diameter'};
            app.SuccessivePlots.Text = 'Succession';
            app.SuccessivePlots.Value = true;
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StringParameters(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainWindow)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MainWindow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end