classdef MergeParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow               matlab.ui.Figure
        CancelButton             matlab.ui.control.Button
        OKButton                 matlab.ui.control.Button
        LinearWeightingCheckBox  matlab.ui.control.CheckBox
        MergeOptions             matlab.ui.container.ButtonGroup
        CombinedButton           matlab.ui.control.RadioButton
        SuccessionButton         matlab.ui.control.RadioButton
        NoMergingButton          matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        mergeMode
        linearWeighting

        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mergeMode, linearWeighting)
            switch mergeMode
                case 'combined'
                    app.CombinedButton.Value = true;
                case 'succession'
                    app.SuccessionButton.Value = true;
                case 'none'
                    app.NoMergingButton.Value = true;
            end

            app.LinearWeightingCheckBox.Value = linearWeighting;

            MergeOptionsChangedCallback(app)

            app.mainapp = mainapp;
        end

        % Selection changed function: MergeOptions
        function MergeOptionsChangedCallback(app, event)
            if app.CombinedButton.Value == true
                app.LinearWeightingCheckBox.Enable = "on";
            else
                app.LinearWeightingCheckBox.Enable = "off";
            end
        end

        % Button pushed function: OKButton
        function OKCallback(app, event)
            if app.CombinedButton.Value
                app.mainapp.mergeMode = 'combined';
            elseif app.SuccessionButton.Value
                app.mainapp.mergeMode = 'succession';
            else
                app.mainapp.mergeMode = 'none';
            end

            app.mainapp.linearWeighting = app.LinearWeightingCheckBox.Value;

            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelCallback(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Stringing parameters';
            app.MainWindow.Icon = fullfile(pathToMLAPP, '..', 'rsdp_resources', 'icon-rsdp.png');
            app.MergeOptions = uibuttongroup(app.MainWindow);
            app.MergeOptions.SelectionChangedFcn = createCallbackFcn(app, @MergeOptionsChangedCallback, true);
            app.MergeOptions.Title = 'Merging';
            app.NoMergingButton = uiradiobutton(app.MergeOptions);
            app.NoMergingButton.Tooltip = {'Successive plots for each pillar diameter'};
            app.NoMergingButton.Text = 'None';
            app.SuccessionButton = uiradiobutton(app.MergeOptions);
            app.SuccessionButton.Tooltip = {'Successive plots for each pillar diameter'};
            app.SuccessionButton.Text = 'Succession';
            app.SuccessionButton.Value = true;
            app.CombinedButton = uiradiobutton(app.MergeOptions);
            app.CombinedButton.Tooltip = {'Combination of all the data together in one plot'};
            app.CombinedButton.Text = 'Combination';
            app.LinearWeightingCheckBox = uicheckbox(app.MainWindow);
            app.LinearWeightingCheckBox.Text = 'Linear weighting between 0.5 and 1 µm';
            app.LinearWeightingCheckBox.Value = true;
            app.OKButton = uibutton(app.MainWindow, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKCallback, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.MainWindow, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCallback, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MergeParameters(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainWindow)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MainWindow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end